classdef ConstraintGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ConstraintAnalysisInputsLabel   matlab.ui.control.Label
        ConstraintValuesLabel           matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        StallSpeedTab                   matlab.ui.container.Tab
        EnterStallParametersLabel       matlab.ui.control.Label
        RequiredorDesiredConstraintDropDown_9Label  matlab.ui.control.Label
        RequiredorDesiredConstraintDropDown_9  matlab.ui.control.DropDown
        EngineTypeDropDown_33Label      matlab.ui.control.Label
        EngineTypeDropDown_33           matlab.ui.control.DropDown
        AfterburnerDropDown_33Label     matlab.ui.control.Label
        AfterburnerDropDown_33          matlab.ui.control.DropDown
        AltitudeftEditField_8Label      matlab.ui.control.Label
        AltitudeftEditField_8           matlab.ui.control.NumericEditField
        VelocityktsEditField_8Label     matlab.ui.control.Label
        VelocityktsEditField_8          matlab.ui.control.NumericEditField
        EstimatedBetaEditField_8Label   matlab.ui.control.Label
        EstimatedBetaEditField_8        matlab.ui.control.NumericEditField
        VelocityMachEditField_8Label    matlab.ui.control.Label
        VelocityMachEditField_8         matlab.ui.control.NumericEditField
        TemperatureSLdegFEditField_5Label  matlab.ui.control.Label
        TemperatureSLdegFEditField_5    matlab.ui.control.NumericEditField
        AddConstriaintButton            matlab.ui.control.Button
        TakeoffTab                      matlab.ui.container.Tab
        EnterTakeoffParametersLabel     matlab.ui.control.Label
        RequiredorDesiredConstraintDropDown_8Label  matlab.ui.control.Label
        RequiredorDesiredConstraintDropDown_8  matlab.ui.control.DropDown
        EngineTypeDropDown_32Label      matlab.ui.control.Label
        EngineTypeDropDown_32           matlab.ui.control.DropDown
        AfterburnerDropDown_32Label     matlab.ui.control.Label
        AfterburnerDropDown_32          matlab.ui.control.DropDown
        AltitudeftEditField_7Label      matlab.ui.control.Label
        AltitudeftEditField_7           matlab.ui.control.NumericEditField
        ClimbRateftminEditFieldLabel    matlab.ui.control.Label
        ClimbRateftminEditField         matlab.ui.control.NumericEditField
        EstimatedBetaEditField_7Label   matlab.ui.control.Label
        EstimatedBetaEditField_7        matlab.ui.control.NumericEditField
        TakeoffVelocityMultiplierEditFieldLabel  matlab.ui.control.Label
        TakeoffVelocityMultiplierEditField  matlab.ui.control.NumericEditField
        TemperatureSLdegFEditField_4Label  matlab.ui.control.Label
        TemperatureSLdegFEditField_4    matlab.ui.control.NumericEditField
        TotalGroundRollftEditFieldLabel  matlab.ui.control.Label
        TotalGroundRollftEditField      matlab.ui.control.NumericEditField
        TotalTakeoffDistanceftEditFieldLabel  matlab.ui.control.Label
        TotalTakeoffDistanceftEditField  matlab.ui.control.NumericEditField
        HeightofObstacleftEditFieldLabel  matlab.ui.control.Label
        HeightofObstacleftEditField     matlab.ui.control.NumericEditField
        RotationTimesecLabel            matlab.ui.control.Label
        RotationTimesecEditField        matlab.ui.control.NumericEditField
        RunwayFrictionCoefficientEditFieldLabel  matlab.ui.control.Label
        RunwayFrictionCoefficientEditField  matlab.ui.control.NumericEditField
        AngleofClimbdegEditFieldLabel   matlab.ui.control.Label
        AngleofClimbdegEditField        matlab.ui.control.NumericEditField
        AddConstriaintButton_2          matlab.ui.control.Button
        HorizontalAccelerationTab       matlab.ui.container.Tab
        EnterAccelParametersLabel       matlab.ui.control.Label
        EngineTypeDropDown_14Label      matlab.ui.control.Label
        EngineTypeDropDown_14           matlab.ui.control.DropDown
        AfterburnerDropDown_14Label     matlab.ui.control.Label
        AfterburnerDropDown_14          matlab.ui.control.DropDown
        AltitudeftEditFieldLabel        matlab.ui.control.Label
        AltitudeftEditField             matlab.ui.control.NumericEditField
        InitialVelocityktsEditFieldLabel  matlab.ui.control.Label
        InitialVelocityktsEditField     matlab.ui.control.NumericEditField
        EstimatedBetaEditFieldLabel     matlab.ui.control.Label
        EstimatedBetaEditField          matlab.ui.control.NumericEditField
        InitialVelocityMachEditFieldLabel  matlab.ui.control.Label
        InitialVelocityMachEditField    matlab.ui.control.NumericEditField
        RequiredorDesiredConstraintDropDown_7Label  matlab.ui.control.Label
        RequiredorDesiredConstraintDropDown_7  matlab.ui.control.DropDown
        TemperatureSLdegFEditField_3Label  matlab.ui.control.Label
        TemperatureSLdegFEditField_3    matlab.ui.control.NumericEditField
        FinalVelocityktsEditFieldLabel  matlab.ui.control.Label
        FinalVelocityktsEditField       matlab.ui.control.NumericEditField
        FinalVelocityMachEditFieldLabel  matlab.ui.control.Label
        FinalVelocityMachEditField      matlab.ui.control.NumericEditField
        TimetoAcceleratesecEditFieldLabel  matlab.ui.control.Label
        TimetoAcceleratesecEditField    matlab.ui.control.NumericEditField
        AddConstriaintButton_3          matlab.ui.control.Button
        ClimbTab                        matlab.ui.container.Tab
        EnterClimbParametersLabel       matlab.ui.control.Label
        RequiredorDesiredConstraintDropDown_6Label  matlab.ui.control.Label
        RequiredorDesiredConstraintDropDown_6  matlab.ui.control.DropDown
        EngineTypeDropDown_27Label      matlab.ui.control.Label
        EngineTypeDropDown_27           matlab.ui.control.DropDown
        AfterburnerDropDown_27Label     matlab.ui.control.Label
        AfterburnerDropDown_27          matlab.ui.control.DropDown
        AltitudeftEditField_2Label      matlab.ui.control.Label
        AltitudeftEditField_2           matlab.ui.control.NumericEditField
        VelocityktsEditField_2Label     matlab.ui.control.Label
        VelocityktsEditField_2          matlab.ui.control.NumericEditField
        EstimatedBetaEditField_2Label   matlab.ui.control.Label
        EstimatedBetaEditField_2        matlab.ui.control.NumericEditField
        VelocityMachEditField_2Label    matlab.ui.control.Label
        VelocityMachEditField_2         matlab.ui.control.NumericEditField
        TemperatureSLdegFEditField_2Label  matlab.ui.control.Label
        TemperatureSLdegFEditField_2    matlab.ui.control.NumericEditField
        ClimbRateftminEditField_2Label  matlab.ui.control.Label
        ClimbRateftminEditField_2       matlab.ui.control.NumericEditField
        AddConstriaintButton_4          matlab.ui.control.Button
        CruiseTab                       matlab.ui.container.Tab
        EnterCruiseParametersLabel      matlab.ui.control.Label
        RequiredorDesiredConstraintDropDown_5Label  matlab.ui.control.Label
        RequiredorDesiredConstraintDropDown_5  matlab.ui.control.DropDown
        EngineTypeDropDown_28Label      matlab.ui.control.Label
        EngineTypeDropDown_28           matlab.ui.control.DropDown
        AfterburnerDropDown_28Label     matlab.ui.control.Label
        AfterburnerDropDown_28          matlab.ui.control.DropDown
        AltitudeftEditField_3Label      matlab.ui.control.Label
        AltitudeftEditField_3           matlab.ui.control.NumericEditField
        VelocityktsEditField_3Label     matlab.ui.control.Label
        VelocityktsEditField_3          matlab.ui.control.NumericEditField
        EstimatedBetaEditField_3Label   matlab.ui.control.Label
        EstimatedBetaEditField_3        matlab.ui.control.NumericEditField
        VelocityMachEditField_3Label    matlab.ui.control.Label
        VelocityMachEditField_3         matlab.ui.control.NumericEditField
        TemperatureSLdegFEditFieldLabel  matlab.ui.control.Label
        TemperatureSLdegFEditField      matlab.ui.control.NumericEditField
        AddConstriaintButton_5          matlab.ui.control.Button
        DashTab                         matlab.ui.container.Tab
        EnterDashParametersLabel        matlab.ui.control.Label
        RequiredorDesiredConstraintDropDown_4Label  matlab.ui.control.Label
        RequiredorDesiredConstraintDropDown_4  matlab.ui.control.DropDown
        EngineTypeDropDown_29Label      matlab.ui.control.Label
        EngineTypeDropDown_29           matlab.ui.control.DropDown
        AfterburnerDropDown_29Label     matlab.ui.control.Label
        AfterburnerDropDown_29          matlab.ui.control.DropDown
        AltitudeftEditField_4Label      matlab.ui.control.Label
        AltitudeftEditField_4           matlab.ui.control.NumericEditField
        VelocityktsEditField_4Label     matlab.ui.control.Label
        VelocityktsEditField_4          matlab.ui.control.NumericEditField
        EstimatedBetaEditField_4Label   matlab.ui.control.Label
        EstimatedBetaEditField_4        matlab.ui.control.NumericEditField
        VelocityMachEditField_4Label    matlab.ui.control.Label
        VelocityMachEditField_4         matlab.ui.control.NumericEditField
        TemperatureSLdegFEditField_6Label  matlab.ui.control.Label
        TemperatureSLdegFEditField_6    matlab.ui.control.NumericEditField
        AddConstriaintButton_6          matlab.ui.control.Button
        TurnTab                         matlab.ui.container.Tab
        EnterTurnParametersLabel        matlab.ui.control.Label
        RequiredorDesiredConstraintDropDown_3Label  matlab.ui.control.Label
        RequiredorDesiredConstraintDropDown_3  matlab.ui.control.DropDown
        EngineTypeDropDown_30Label      matlab.ui.control.Label
        EngineTypeDropDown_30           matlab.ui.control.DropDown
        AfterburnerDropDown_30Label     matlab.ui.control.Label
        AfterburnerDropDown_30          matlab.ui.control.DropDown
        AltitudeftEditField_5Label      matlab.ui.control.Label
        AltitudeftEditField_5           matlab.ui.control.NumericEditField
        VelocityktsEditField_5Label     matlab.ui.control.Label
        VelocityktsEditField_5          matlab.ui.control.NumericEditField
        EstimatedBetaEditField_5Label   matlab.ui.control.Label
        EstimatedBetaEditField_5        matlab.ui.control.NumericEditField
        VelocityMachEditField_5Label    matlab.ui.control.Label
        VelocityMachEditField_5         matlab.ui.control.NumericEditField
        TemperatureSLdegFEditField_7Label  matlab.ui.control.Label
        TemperatureSLdegFEditField_7    matlab.ui.control.NumericEditField
        LoadFactorEditFieldLabel        matlab.ui.control.Label
        LoadFactorEditField             matlab.ui.control.NumericEditField
        TurnRadiusftEditFieldLabel      matlab.ui.control.Label
        TurnRadiusftEditField           matlab.ui.control.NumericEditField
        AddConstriaintButton_7          matlab.ui.control.Button
        CeilingTab                      matlab.ui.container.Tab
        EnterCeilingParametersLabel     matlab.ui.control.Label
        RequiredorDesiredConstraintDropDownLabel  matlab.ui.control.Label
        RequiredorDesiredConstraintDropDown  matlab.ui.control.DropDown
        EngineTypeDropDown_31Label      matlab.ui.control.Label
        EngineTypeDropDown_31           matlab.ui.control.DropDown
        AfterburnerDropDown_31Label     matlab.ui.control.Label
        AfterburnerDropDown_31          matlab.ui.control.DropDown
        AltitudeftEditField_6Label      matlab.ui.control.Label
        AltitudeftEditField_6           matlab.ui.control.NumericEditField
        VelocityktsEditField_6Label     matlab.ui.control.Label
        VelocityktsEditField_6          matlab.ui.control.NumericEditField
        EstimatedBetaEditField_6Label   matlab.ui.control.Label
        EstimatedBetaEditField_6        matlab.ui.control.NumericEditField
        VelocityMachEditField_6Label    matlab.ui.control.Label
        VelocityMachEditField_6         matlab.ui.control.NumericEditField
        TemperatureSLdegFEditField_8Label  matlab.ui.control.Label
        TemperatureSLdegFEditField_8    matlab.ui.control.NumericEditField
        ClimbRateftminEditField_3Label  matlab.ui.control.Label
        ClimbRateftminEditField_3       matlab.ui.control.NumericEditField
        AddConstriaintButton_8          matlab.ui.control.Button
        LandingTab                      matlab.ui.container.Tab
        EnterLandingParametersLabel     matlab.ui.control.Label
        RequiredorDesiredConstraintDropDown_2Label  matlab.ui.control.Label
        RequiredorDesiredConstraintDropDown_2  matlab.ui.control.DropDown
        EngineTypeDropDown_34Label      matlab.ui.control.Label
        EngineTypeDropDown_34           matlab.ui.control.DropDown
        AfterburnerDropDown_34Label     matlab.ui.control.Label
        AfterburnerDropDown_34          matlab.ui.control.DropDown
        AltitudeftEditField_9Label      matlab.ui.control.Label
        AltitudeftEditField_9           matlab.ui.control.NumericEditField
        VelocityktsEditField_9Label     matlab.ui.control.Label
        VelocityktsEditField_9          matlab.ui.control.NumericEditField
        EstimatedBetaEditField_9Label   matlab.ui.control.Label
        EstimatedBetaEditField_9        matlab.ui.control.NumericEditField
        VelocityMachEditField_9Label    matlab.ui.control.Label
        VelocityMachEditField_9         matlab.ui.control.NumericEditField
        TemperatureSLdegFEditField_9Label  matlab.ui.control.Label
        TemperatureSLdegFEditField_9    matlab.ui.control.NumericEditField
        HeightofObstacleftEditField_2Label  matlab.ui.control.Label
        HeightofObstacleftEditField_2   matlab.ui.control.NumericEditField
        FreeRollTimesecEditFieldLabel   matlab.ui.control.Label
        FreeRollTimesecEditField        matlab.ui.control.NumericEditField
        RunwayFrictionCoefficientEditField_2Label  matlab.ui.control.Label
        RunwayFrictionCoefficientEditField_2  matlab.ui.control.NumericEditField
        ThrustReversersThrustEditFieldLabel  matlab.ui.control.Label
        ThrustReversersThrustEditField  matlab.ui.control.NumericEditField
        TotalLandingRollftEditFieldLabel  matlab.ui.control.Label
        TotalLandingRollftEditField     matlab.ui.control.NumericEditField
        TotalLandingDistanceftEditFieldLabel  matlab.ui.control.Label
        TotalLandingDistanceftEditField  matlab.ui.control.NumericEditField
        TouchdownVelocityMultiplierEditFieldLabel  matlab.ui.control.Label
        TouchdownVelocityMultiplierEditField  matlab.ui.control.NumericEditField
        ObstacleVelocityMultiplierEditFieldLabel  matlab.ui.control.Label
        ObstacleVelocityMultiplierEditField  matlab.ui.control.NumericEditField
        AddConstriaintButton_9          matlab.ui.control.Button
        SubmitButton                    matlab.ui.control.Button
        ChooseConstraintsLabel          matlab.ui.control.Label
        StallSpeedCheckBox              matlab.ui.control.CheckBox
        TakeoffCheckBox                 matlab.ui.control.CheckBox
        HorizontalAccelerationCheckBox  matlab.ui.control.CheckBox
        ClimbCheckBox                   matlab.ui.control.CheckBox
        CruiseCheckBox                  matlab.ui.control.CheckBox
        DashCheckBox                    matlab.ui.control.CheckBox
        TurnCheckBox                    matlab.ui.control.CheckBox
        CeilingCheckBox                 matlab.ui.control.CheckBox
        LandingCheckBox                 matlab.ui.control.CheckBox
        FileNameLabel                   matlab.ui.control.Label
        FileNameEditField               matlab.ui.control.EditField
        SaveNameButton                  matlab.ui.control.Button
    end

    
    properties (Access = public)
        reqs
        i
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.i = 2;
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            fileName = [app.FileNameEditField.Value,'.csv'];
            writeReq(fileName);
            delete(app)
        end

        % Value changed function: VelocityktsEditField_8
        function VelocityktsEditField_8ValueChanged(app, event)
            if app.VelocityktsEditField_8.Value ~= 0
                app.VelocityMachEditField_8Label.BackgroundColor = [1 0 0];
                app.VelocityMachEditField_8.BackgroundColor = [1 0 0];
                app.VelocityMachEditField_8.Enable = 'off';
            elseif app.VelocityktsEditField_8.Value == 0
                app.VelocityMachEditField_8Label.BackgroundColor = [0.9412 0.9412 0.9412];
                app.VelocityMachEditField_8.BackgroundColor = [1 1 1];
                app.VelocityMachEditField_8.Enable = 'on';
            end
        end

        % Value changed function: VelocityMachEditField_8
        function VelocityMachEditField_8ValueChanged(app, event)
            if app.VelocityMachEditField_8.Value ~= 0
                app.VelocityktsEditField_8Label.BackgroundColor = [1 0 0];
                app.VelocityktsEditField_8.BackgroundColor = [1 0 0];
                app.VelocityktsEditField_8.Enable = 'off';
            elseif app.VelocityMachEditField_8.Value == 0
                app.VelocityktsEditField_8Label.BackgroundColor = [0.9412 0.9412 0.9412];
                app.VelocityktsEditField_8.BackgroundColor = [1 1 1];
                app.VelocityktsEditField_8.Enable = 'on';
            end
        end

        % Value changed function: StallSpeedCheckBox
        function StallSpeedCheckBoxValueChanged(app, event)
            if app.StallSpeedCheckBox.Value == false
                app.RequiredorDesiredConstraintDropDown_9.Enable = 'off';
                app.EngineTypeDropDown_33.Enable = 'off';
                app.AfterburnerDropDown_33.Enable = 'off';
                app.AltitudeftEditField_8.Enable = 'off';
                app.VelocityktsEditField_8.Enable = 'off';
                app.EstimatedBetaEditField_8.Enable = 'off';
                app.VelocityMachEditField_8.Enable = 'off';
                app.TemperatureSLdegFEditField_5.Enable = 'off';
                app.AddConstriaintButton.Enable = 'off';
            elseif app.StallSpeedCheckBox.Value == true
                app.RequiredorDesiredConstraintDropDown_9.Enable = 'on';
                app.EngineTypeDropDown_33.Enable = 'on';
                app.AfterburnerDropDown_33.Enable = 'on';
                app.AltitudeftEditField_8.Enable = 'on';
                app.VelocityktsEditField_8.Enable = 'on';
                app.EstimatedBetaEditField_8.Enable = 'on';
                app.VelocityMachEditField_8.Enable = 'on';
                app.TemperatureSLdegFEditField_5.Enable = 'on';
                app.AddConstriaintButton.Enable = 'on';
            end
        end

        % Value changed function: TakeoffCheckBox
        function TakeoffCheckBoxValueChanged(app, event)
            if app.TakeoffCheckBox.Value == false
                app.RequiredorDesiredConstraintDropDown_8.Enable = 'off';
                app.EngineTypeDropDown_32.Enable = 'off';
                app.AfterburnerDropDown_32.Enable = 'off';
                app.AltitudeftEditField_7.Enable = 'off';
                app.ClimbRateftminEditField.Enable = 'off';
                app.EstimatedBetaEditField_7.Enable = 'off';
                app.TakeoffVelocityMultiplierEditField.Enable = 'off';
                app.TemperatureSLdegFEditField_4.Enable = 'off';
                app.TotalGroundRollftEditField.Enable = 'off';
                app.TotalTakeoffDistanceftEditField.Enable = 'off';
                app.HeightofObstacleftEditField.Enable = 'off';
                app.RotationTimesecEditField.Enable = 'off';
                app.RunwayFrictionCoefficientEditField.Enable = 'off';
                app.AngleofClimbdegEditField.Enable = 'off';
                app.AddConstriaintButton_2.Enable = 'off';
            elseif app.TakeoffCheckBox.Value == true
                app.RequiredorDesiredConstraintDropDown_8.Enable = 'on';
                app.EngineTypeDropDown_32.Enable = 'on';
                app.AfterburnerDropDown_32.Enable = 'on';
                app.AltitudeftEditField_7.Enable = 'on';
                app.ClimbRateftminEditField.Enable = 'on';
                app.EstimatedBetaEditField_7.Enable = 'on';
                app.TakeoffVelocityMultiplierEditField.Enable = 'on';
                app.TemperatureSLdegFEditField_4.Enable = 'on';
                app.TotalGroundRollftEditField.Enable = 'on';
                app.TotalTakeoffDistanceftEditField.Enable = 'on';
                app.HeightofObstacleftEditField.Enable = 'on';
                app.RotationTimesecEditField.Enable = 'on';
                app.RunwayFrictionCoefficientEditField.Enable = 'on';
                app.AngleofClimbdegEditField.Enable = 'on';
                app.AddConstriaintButton_2.Enable = 'on';
            end
        end

        % Value changed function: TotalGroundRollftEditField
        function TotalGroundRollftEditFieldValueChanged(app, event)
            if app.TotalGroundRollftEditField.Value ~= 0
                app.TotalTakeoffDistanceftEditFieldLabel.BackgroundColor = [1 0 0];
                app.TotalTakeoffDistanceftEditField.BackgroundColor = [1 0 0];
                app.TotalTakeoffDistanceftEditField.Enable = 'off';
            elseif app.TotalGroundRollftEditField.Value == 0
                app.TotalTakeoffDistanceftEditFieldLabel.BackgroundColor = [0.9412 0.9412 0.9412];
                app.TotalTakeoffDistanceftEditField.BackgroundColor = [1 1 1];
                app.TotalTakeoffDistanceftEditField.Enable = 'on';
            end
        end

        % Value changed function: TotalTakeoffDistanceftEditField
        function TotalTakeoffDistanceftEditFieldValueChanged(app, event)
            if app.TotalTakeoffDistanceftEditField.Value ~= 0
                app.TotalGroundRollftEditFieldLabel.BackgroundColor = [1 0 0];
                app.TotalGroundRollftEditField.BackgroundColor = [1 0 0];
                app.TotalGroundRollftEditField.Enable = 'off';
            elseif app.TotalTakeoffDistanceftEditField.Value == 0
                app.TotalGroundRollftEditFieldLabel.BackgroundColor = [0.9412 0.9412 0.9412];
                app.TotalGroundRollftEditField.BackgroundColor = [1 1 1];
                app.TotalGroundRollftEditField.Enable = 'on';
            end
        end

        % Value changed function: ClimbRateftminEditField
        function ClimbRateftminEditFieldValueChanged(app, event)
            if app.ClimbRateftminEditField.Value ~= 0
                app.AngleofClimbdegEditFieldLabel.BackgroundColor = [1 0 0];
                app.AngleofClimbdegEditField.BackgroundColor = [1 0 0];
                app.AngleofClimbdegEditField.Enable = 'off';
            elseif app.ClimbRateftminEditField.Value == 0
                app.AngleofClimbdegEditFieldLabel.BackgroundColor = [0.9412 0.9412 0.9412];
                app.AngleofClimbdegEditField.BackgroundColor = [1 1 1];
                app.AngleofClimbdegEditField.Enable = 'on';
            end
        end

        % Value changed function: AngleofClimbdegEditField
        function AngleofClimbdegEditFieldValueChanged(app, event)
            if app.AngleofClimbdegEditField.Value ~= 0
                app.ClimbRateftminEditFieldLabel.BackgroundColor = [1 0 0];
                app.ClimbRateftminEditField.BackgroundColor = [1 0 0];
                app.ClimbRateftminEditField.Enable = 'off';
            elseif app.AngleofClimbdegEditField.Value == 0
                app.ClimbRateftminEditFieldLabel.BackgroundColor = [0.9412 0.9412 0.9412];
                app.ClimbRateftminEditField.BackgroundColor = [1 1 1];
                app.ClimbRateftminEditField.Enable = 'on';
            end
        end

        % Value changed function: HorizontalAccelerationCheckBox
        function HorizontalAccelerationCheckBoxValueChanged(app, event)
            if app.HorizontalAccelerationCheckBox.Value == false
                app.EngineTypeDropDown_14.Enable = 'off';
                app.AfterburnerDropDown_14.Enable = 'off';
                app.AltitudeftEditField.Enable = 'off';
                app.InitialVelocityktsEditField.Enable = 'off';
                app.EstimatedBetaEditField.Enable = 'off';
                app.InitialVelocityMachEditField.Enable = 'off';
                app.RequiredorDesiredConstraintDropDown_7.Enable = 'off';
                app.TemperatureSLdegFEditField_3.Enable = 'off';
                app.FinalVelocityktsEditField.Enable = 'off';
                app.FinalVelocityMachEditField.Enable = 'off';
                app.TimetoAcceleratesecEditField.Enable = 'off';
                app.AddConstriaintButton_3.Enable = 'off';
            elseif app.HorizontalAccelerationCheckBox.Value == true
                app.EngineTypeDropDown_14.Enable = 'on';
                app.AfterburnerDropDown_14.Enable = 'on';
                app.AltitudeftEditField.Enable = 'on';
                app.InitialVelocityktsEditField.Enable = 'on';
                app.EstimatedBetaEditField.Enable = 'on';
                app.InitialVelocityMachEditField.Enable = 'on';
                app.RequiredorDesiredConstraintDropDown_7.Enable = 'on';
                app.TemperatureSLdegFEditField_3.Enable = 'on';
                app.FinalVelocityktsEditField.Enable = 'on';
                app.FinalVelocityMachEditField.Enable = 'on';
                app.TimetoAcceleratesecEditField.Enable = 'on';
                app.AddConstriaintButton_3.Enable = 'on';
            end
        end

        % Value changed function: ClimbCheckBox
        function ClimbCheckBoxValueChanged(app, event)
            if app.ClimbCheckBox.Value == false
                app.RequiredorDesiredConstraintDropDown_6.Enable = 'off';
                app.EngineTypeDropDown_27.Enable = 'off';
                app.AfterburnerDropDown_27.Enable = 'off';
                app.AltitudeftEditField_2.Enable = 'off';
                app.VelocityktsEditField_2.Enable = 'off';
                app.EstimatedBetaEditField_2.Enable = 'off';
                app.VelocityMachEditField_2.Enable = 'off';
                app.TemperatureSLdegFEditField_2.Enable = 'off';
                app.ClimbRateftminEditField_2.Enable = 'off';
                app.AddConstriaintButton_4.Enable = 'off';
            elseif app.ClimbCheckBox.Value == true
                app.RequiredorDesiredConstraintDropDown_6.Enable = 'on';
                app.EngineTypeDropDown_27.Enable = 'on';
                app.AfterburnerDropDown_27.Enable = 'on';
                app.AltitudeftEditField_2.Enable = 'on';
                app.VelocityktsEditField_2.Enable = 'on';
                app.EstimatedBetaEditField_2.Enable = 'on';
                app.VelocityMachEditField_2.Enable = 'on';
                app.TemperatureSLdegFEditField_2.Enable = 'on';
                app.ClimbRateftminEditField_2.Enable = 'on';
                app.AddConstriaintButton_4.Enable = 'on';
            end
        end

        % Value changed function: CruiseCheckBox
        function CruiseCheckBoxValueChanged(app, event)
            if app.CruiseCheckBox.Value == false
                app.RequiredorDesiredConstraintDropDown_5.Enable = 'off';
                app.EngineTypeDropDown_28.Enable = 'off';
                app.AfterburnerDropDown_28.Enable = 'off';
                app.AltitudeftEditField_3.Enable = 'off';
                app.VelocityktsEditField_3.Enable = 'off';
                app.EstimatedBetaEditField_3.Enable = 'off';
                app.VelocityMachEditField_3.Enable = 'off';
                app.TemperatureSLdegFEditField.Enable = 'off';
                app.AddConstriaintButton_5.Enable = 'off';
            elseif app.CruiseCheckBox.Value == true
                app.RequiredorDesiredConstraintDropDown_5.Enable = 'on';
                app.EngineTypeDropDown_28.Enable = 'on';
                app.AfterburnerDropDown_28.Enable = 'on';
                app.AltitudeftEditField_3.Enable = 'on';
                app.VelocityktsEditField_3.Enable = 'on';
                app.EstimatedBetaEditField_3.Enable = 'on';
                app.VelocityMachEditField_3.Enable = 'on';
                app.TemperatureSLdegFEditField.Enable = 'on';
                app.AddConstriaintButton_5.Enable = 'on';
            end
        end

        % Value changed function: DashCheckBox
        function DashCheckBoxValueChanged(app, event)
            if app.DashCheckBox.Value == false
                app.RequiredorDesiredConstraintDropDown_4.Enable = 'off';
                app.EngineTypeDropDown_29.Enable = 'off';
                app.AfterburnerDropDown_29.Enable = 'off';
                app.AltitudeftEditField_4.Enable = 'off';
                app.VelocityktsEditField_4.Enable = 'off';
                app.EstimatedBetaEditField_4.Enable = 'off';
                app.VelocityMachEditField_4.Enable = 'off';
                app.TemperatureSLdegFEditField_6.Enable = 'off';
                app.AddConstriaintButton_6.Enable = 'off';
            elseif app.DashCheckBox.Value == true
                app.RequiredorDesiredConstraintDropDown_4.Enable = 'on';
                app.EngineTypeDropDown_29.Enable = 'on';
                app.AfterburnerDropDown_29.Enable = 'on';
                app.AltitudeftEditField_4.Enable = 'on';
                app.VelocityktsEditField_4.Enable = 'on';
                app.EstimatedBetaEditField_4.Enable = 'on';
                app.VelocityMachEditField_4.Enable = 'on';
                app.TemperatureSLdegFEditField_6.Enable = 'on';
                app.AddConstriaintButton_6.Enable = 'on';
            end
        end

        % Value changed function: TurnCheckBox
        function TurnCheckBoxValueChanged(app, event)
            if app.TurnCheckBox.Value == false
                app.RequiredorDesiredConstraintDropDown_3.Enable = 'off';
                app.EngineTypeDropDown_30.Enable = 'off';
                app.AfterburnerDropDown_30.Enable = 'off';
                app.AltitudeftEditField_5.Enable = 'off';
                app.VelocityktsEditField_5.Enable = 'off';
                app.EstimatedBetaEditField_5.Enable = 'off';
                app.VelocityMachEditField_5.Enable = 'off';
                app.TemperatureSLdegFEditField_7.Enable = 'off';
                app.LoadFactorEditField.Enable = 'off';
                app.TurnRadiusftEditField.Enable = 'off';
                app.AddConstriaintButton_7.Enable = 'off';
            elseif app.TurnCheckBox.Value == true
                app.RequiredorDesiredConstraintDropDown_3.Enable = 'on';
                app.EngineTypeDropDown_30.Enable = 'on';
                app.AfterburnerDropDown_30.Enable = 'on';
                app.AltitudeftEditField_5.Enable = 'on';
                app.VelocityktsEditField_5.Enable = 'on';
                app.EstimatedBetaEditField_5.Enable = 'on';
                app.VelocityMachEditField_5.Enable = 'on';
                app.TemperatureSLdegFEditField_7.Enable = 'on';
                app.LoadFactorEditField.Enable = 'on';
                app.TurnRadiusftEditField.Enable = 'on';
                app.AddConstriaintButton_7.Enable = 'on';
            end
        end

        % Value changed function: CeilingCheckBox
        function CeilingCheckBoxValueChanged(app, event)
            if app.CeilingCheckBox.Value == false
                app.RequiredorDesiredConstraintDropDown.Enable = 'off';
                app.EngineTypeDropDown_31.Enable = 'off';
                app.AfterburnerDropDown_31.Enable = 'off';
                app.AltitudeftEditField_6.Enable = 'off';
                app.VelocityktsEditField_6.Enable = 'off';
                app.EstimatedBetaEditField_6.Enable = 'off';
                app.VelocityMachEditField_6.Enable = 'off';
                app.TemperatureSLdegFEditField_8.Enable = 'off';
                app.ClimbRateftminEditField_3.Enable = 'off';
                app.AddConstriaintButton_8.Enable = 'off';
            elseif app.CeilingCheckBox.Value == true
                app.RequiredorDesiredConstraintDropDown.Enable = 'on';
                app.EngineTypeDropDown_31.Enable = 'on';
                app.AfterburnerDropDown_31.Enable = 'on';
                app.AltitudeftEditField_6.Enable = 'on';
                app.VelocityktsEditField_6.Enable = 'on';
                app.EstimatedBetaEditField_6.Enable = 'on';
                app.VelocityMachEditField_6.Enable = 'on';
                app.TemperatureSLdegFEditField_8.Enable = 'on';
                app.ClimbRateftminEditField_3.Enable = 'on';
                app.AddConstriaintButton_8.Enable = 'on';
            end
        end

        % Value changed function: LandingCheckBox
        function LandingCheckBoxValueChanged(app, event)
            if app.LandingCheckBox.Value == false
                app.RequiredorDesiredConstraintDropDown_2.Enable = 'off';
                app.EngineTypeDropDown_34.Enable = 'off';
                app.AfterburnerDropDown_34.Enable = 'off';
                app.AltitudeftEditField_9.Enable = 'off';
                app.VelocityktsEditField_9.Enable = 'off';
                app.EstimatedBetaEditField_9.Enable = 'off';
                app.VelocityMachEditField_9.Enable = 'off';
                app.TemperatureSLdegFEditField_9.Enable = 'off';
                app.HeightofObstacleftEditField_2.Enable = 'off';
                app.FreeRollTimesecEditField.Enable = 'off';
                app.RunwayFrictionCoefficientEditField_2.Enable = 'off';
                app.ThrustReversersThrustEditField.Enable = 'off';
                app.TotalLandingRollftEditField.Enable = 'off';
                app.TotalLandingDistanceftEditField.Enable = 'off';
                app.TouchdownVelocityMultiplierEditField.Enable = 'off';
                app.ObstacleVelocityMultiplierEditField.Enable = 'off';
                app.AddConstriaintButton_9.Enable = 'off';
            elseif app.LandingCheckBox.Value == true
                app.RequiredorDesiredConstraintDropDown_2.Enable = 'on';
                app.EngineTypeDropDown_34.Enable = 'on';
                app.AfterburnerDropDown_34.Enable = 'on';
                app.AltitudeftEditField_9.Enable = 'on';
                app.VelocityktsEditField_9.Enable = 'on';
                app.EstimatedBetaEditField_9.Enable = 'on';
                app.VelocityMachEditField_9.Enable = 'on';
                app.TemperatureSLdegFEditField_9.Enable = 'on';
                app.HeightofObstacleftEditField_2.Enable = 'on';
                app.FreeRollTimesecEditField.Enable = 'on';
                app.RunwayFrictionCoefficientEditField_2.Enable = 'on';
                app.ThrustReversersThrustEditField.Enable = 'on';
                app.TotalLandingRollftEditField.Enable = 'on';
                app.TotalLandingDistanceftEditField.Enable = 'on';
                app.TouchdownVelocityMultiplierEditField.Enable = 'on';
                app.ObstacleVelocityMultiplierEditField.Enable = 'on';
                app.AddConstriaintButton_9.Enable = 'on';
            end
        end

        % Value changed function: InitialVelocityktsEditField
        function InitialVelocityktsEditFieldValueChanged(app, event)
            if app.InitialVelocityktsEditField.Value ~= 0
                app.InitialVelocityMachEditFieldLabel.BackgroundColor = [1 0 0];
                app.InitialVelocityMachEditField.BackgroundColor = [1 0 0];
                app.InitialVelocityMachEditField.Enable = 'off';
            elseif app.InitialVelocityktsEditField.Value == 0
                app.InitialVelocityMachEditFieldLabel.BackgroundColor = [0.9412 0.9412 0.9412];
                app.InitialVelocityMachEditField.BackgroundColor = [1 1 1];
                app.InitialVelocityMachEditField.Enable = 'on';
            end
        end

        % Value changed function: InitialVelocityMachEditField
        function InitialVelocityMachEditFieldValueChanged(app, event)
            if app.InitialVelocityMachEditField.Value ~= 0
                app.InitialVelocityktsEditFieldLabel.BackgroundColor = [1 0 0];
                app.InitialVelocityktsEditField.BackgroundColor = [1 0 0];
                app.InitialVelocityktsEditField.Enable = 'off';
            elseif app.InitialVelocityMachEditField.Value == 0
                app.InitialVelocityktsEditFieldLabel.BackgroundColor = [0.9412 0.9412 0.9412];
                app.InitialVelocityktsEditField.BackgroundColor = [1 1 1];
                app.InitialVelocityktsEditField.Enable = 'on';
            end
        end

        % Value changed function: FinalVelocityktsEditField
        function FinalVelocityktsEditFieldValueChanged(app, event)
            if app.FinalVelocityktsEditField.Value ~= 0
                app.FinalVelocityMachEditFieldLabel.BackgroundColor = [1 0 0];
                app.FinalVelocityMachEditField.BackgroundColor = [1 0 0];
                app.FinalVelocityMachEditField.Enable = 'off';
            elseif app.FinalVelocityktsEditField.Value == 0
                app.FinalVelocityMachEditFieldLabel.BackgroundColor = [0.9412 0.9412 0.9412];
                app.FinalVelocityMachEditField.BackgroundColor = [1 1 1];
                app.FinalVelocityMachEditField.Enable = 'on';
            end
        end

        % Value changed function: FinalVelocityMachEditField
        function FinalVelocityMachEditFieldValueChanged(app, event)
            if app.FinalVelocityMachEditField.Value ~= 0
                app.FinalVelocityktsEditFieldLabel.BackgroundColor = [1 0 0];
                app.FinalVelocityktsEditField.BackgroundColor = [1 0 0];
                app.FinalVelocityktsEditField.Enable = 'off';
            elseif app.FinalVelocityMachEditField.Value == 0
                app.FinalVelocityktsEditFieldLabel.BackgroundColor = [0.9412 0.9412 0.9412];
                app.FinalVelocityktsEditField.BackgroundColor = [1 1 1];
                app.FinalVelocityktsEditField.Enable = 'on';
            end
        end

        % Value changed function: VelocityktsEditField_2
        function VelocityktsEditField_2ValueChanged(app, event)
            if app.VelocityktsEditField_2.Value ~= 0
                app.VelocityMachEditField_2Label.BackgroundColor = [1 0 0];
                app.VelocityMachEditField_2.BackgroundColor = [1 0 0];
                app.VelocityMachEditField_2.Enable = 'off';
            elseif app.VelocityktsEditField_2.Value == 0
                app.VelocityMachEditField_2Label.BackgroundColor = [0.9412 0.9412 0.9412];
                app.VelocityMachEditField_2.BackgroundColor = [1 1 1];
                app.VelocityMachEditField_2.Enable = 'on';
            end
        end

        % Value changed function: VelocityMachEditField_2
        function VelocityMachEditField_2ValueChanged(app, event)
            if app.VelocityMachEditField_2.Value ~= 0
                app.VelocityktsEditField_2Label.BackgroundColor = [1 0 0];
                app.VelocityktsEditField_2.BackgroundColor = [1 0 0];
                app.VelocityktsEditField_2.Enable = 'off';
            elseif app.VelocityMachEditField_2.Value == 0
                app.VelocityktsEditField_2Label.BackgroundColor = [0.9412 0.9412 0.9412];
                app.VelocityktsEditField_2.BackgroundColor = [1 1 1];
                app.VelocityktsEditField_2.Enable = 'on';
            end
        end

        % Value changed function: VelocityktsEditField_3
        function VelocityktsEditField_3ValueChanged(app, event)
            if app.VelocityktsEditField_3.Value ~= 0
                app.VelocityMachEditField_3Label.BackgroundColor = [1 0 0];
                app.VelocityMachEditField_3.BackgroundColor = [1 0 0];
                app.VelocityMachEditField_3.Enable = 'off';
            elseif app.VelocityktsEditField_3.Value == 0
                app.VelocityMachEditField_3Label.BackgroundColor = [0.9412 0.9412 0.9412];
                app.VelocityMachEditField_3.BackgroundColor = [1 1 1];
                app.VelocityMachEditField_3.Enable = 'on';
            end
        end

        % Value changed function: VelocityMachEditField_3
        function VelocityMachEditField_3ValueChanged(app, event)
            if app.VelocityMachEditField_3.Value ~= 0
                app.VelocityktsEditField_3Label.BackgroundColor = [1 0 0];
                app.VelocityktsEditField_3.BackgroundColor = [1 0 0];
                app.VelocityktsEditField_3.Enable = 'off';
            elseif app.VelocityMachEditField_3.Value == 0
                app.VelocityktsEditField_3Label.BackgroundColor = [0.9412 0.9412 0.9412];
                app.VelocityktsEditField_3.BackgroundColor = [1 1 1];
                app.VelocityktsEditField_3.Enable = 'on';
            end
        end

        % Value changed function: VelocityktsEditField_4
        function VelocityktsEditField_4ValueChanged(app, event)
            if app.VelocityktsEditField_4.Value ~= 0
                app.VelocityMachEditField_4Label.BackgroundColor = [1 0 0];
                app.VelocityMachEditField_4.BackgroundColor = [1 0 0];
                app.VelocityMachEditField_4.Enable = 'off';
            elseif app.VelocityktsEditField_4.Value == 0
                app.VelocityMachEditField_4Label.BackgroundColor = [0.9412 0.9412 0.9412];
                app.VelocityMachEditField_4.BackgroundColor = [1 1 1];
                app.VelocityMachEditField_4.Enable = 'on';
            end
        end

        % Value changed function: VelocityMachEditField_4
        function VelocityMachEditField_4ValueChanged(app, event)
            if app.VelocityMachEditField_4.Value ~= 0
                app.VelocityktsEditField_4Label.BackgroundColor = [1 0 0];
                app.VelocityktsEditField_4.BackgroundColor = [1 0 0];
                app.VelocityktsEditField_4.Enable = 'off';
            elseif app.VelocityMachEditField_4.Value == 0
                app.VelocityktsEditField_4Label.BackgroundColor = [0.9412 0.9412 0.9412];
                app.VelocityktsEditField_4.BackgroundColor = [1 1 1];
                app.VelocityktsEditField_4.Enable = 'on';
            end
        end

        % Value changed function: VelocityktsEditField_5
        function VelocityktsEditField_5ValueChanged(app, event)
            if app.VelocityktsEditField_5.Value ~= 0
                app.VelocityMachEditField_5Label.BackgroundColor = [1 0 0];
                app.VelocityMachEditField_5.BackgroundColor = [1 0 0];
                app.VelocityMachEditField_5.Enable = 'off';
            elseif app.VelocityktsEditField_5.Value == 0
                app.VelocityMachEditField_5Label.BackgroundColor = [0.9412 0.9412 0.9412];
                app.VelocityMachEditField_5.BackgroundColor = [1 1 1];
                app.VelocityMachEditField_5.Enable = 'on';
            end
        end

        % Value changed function: VelocityMachEditField_5
        function VelocityMachEditField_5ValueChanged(app, event)
            if app.VelocityMachEditField_5.Value ~= 0
                app.VelocityktsEditField_5Label.BackgroundColor = [1 0 0];
                app.VelocityktsEditField_5.BackgroundColor = [1 0 0];
                app.VelocityktsEditField_5.Enable = 'off';
            elseif app.VelocityMachEditField_5.Value == 0
                app.VelocityktsEditField_5Label.BackgroundColor = [0.9412 0.9412 0.9412];
                app.VelocityktsEditField_5.BackgroundColor = [1 1 1];
                app.VelocityktsEditField_5.Enable = 'on';
            end
        end

        % Value changed function: LoadFactorEditField
        function LoadFactorEditFieldValueChanged(app, event)
            if app.LoadFactorEditField.Value ~= 0
                app.TurnRadiusftEditFieldLabel.BackgroundColor = [1 0 0];
                app.TurnRadiusftEditField.BackgroundColor = [1 0 0];
                app.TurnRadiusftEditField.Enable = 'off';
            elseif app.LoadFactorEditField.Value == 0
                app.TurnRadiusftEditFieldLabel.BackgroundColor = [0.9412 0.9412 0.9412];
                app.TurnRadiusftEditField.BackgroundColor = [1 1 1];
                app.TurnRadiusftEditField.Enable = 'on';
            end
        end

        % Value changed function: TurnRadiusftEditField
        function TurnRadiusftEditFieldValueChanged(app, event)
            if app.TurnRadiusftEditField.Value ~= 0
                app.LoadFactorEditFieldLabel.BackgroundColor = [1 0 0];
                app.LoadFactorEditField.BackgroundColor = [1 0 0];
                app.LoadFactorEditField.Enable = 'off';
            elseif app.TurnRadiusftEditField.Value == 0
                app.LoadFactorEditFieldLabel.BackgroundColor = [0.9412 0.9412 0.9412];
                app.LoadFactorEditField.BackgroundColor = [1 1 1];
                app.LoadFactorEditField.Enable = 'on';
            end
        end

        % Value changed function: VelocityktsEditField_6
        function VelocityktsEditField_6ValueChanged(app, event)
            if app.VelocityktsEditField_6.Value ~= 0
                app.VelocityMachEditField_6Label.BackgroundColor = [1 0 0];
                app.VelocityMachEditField_6.BackgroundColor = [1 0 0];
                app.VelocityMachEditField_6.Enable = 'off';
            elseif app.VelocityktsEditField_6.Value == 0
                app.VelocityMachEditField_6Label.BackgroundColor = [0.9412 0.9412 0.9412];
                app.VelocityMachEditField_6.BackgroundColor = [1 1 1];
                app.VelocityMachEditField_6.Enable = 'on';
            end
        end

        % Value changed function: VelocityMachEditField_6
        function VelocityMachEditField_6ValueChanged(app, event)
            if app.VelocityMachEditField_6.Value ~= 0
                app.VelocityktsEditField_6Label.BackgroundColor = [1 0 0];
                app.VelocityktsEditField_6.BackgroundColor = [1 0 0];
                app.VelocityktsEditField_6.Enable = 'off';
            elseif app.VelocityMachEditField_6.Value == 0
                app.VelocityktsEditField_6Label.BackgroundColor = [0.9412 0.9412 0.9412];
                app.VelocityktsEditField_6.BackgroundColor = [1 1 1];
                app.VelocityktsEditField_6.Enable = 'on';
            end
        end

        % Value changed function: VelocityktsEditField_9
        function VelocityktsEditField_9ValueChanged(app, event)
            if app.VelocityktsEditField_9.Value ~= 0
                app.VelocityMachEditField_9Label.BackgroundColor = [1 0 0];
                app.VelocityMachEditField_9.BackgroundColor = [1 0 0];
                app.VelocityMachEditField_9.Enable = 'off';
            elseif app.VelocityktsEditField_9.Value == 0
                app.VelocityMachEditField_9Label.BackgroundColor = [0.9412 0.9412 0.9412];
                app.VelocityMachEditField_9.BackgroundColor = [1 1 1];
                app.VelocityMachEditField_9.Enable = 'on';
            end
        end

        % Value changed function: VelocityMachEditField_9
        function VelocityMachEditField_9ValueChanged(app, event)
            if app.VelocityMachEditField_9.Value ~= 0
                app.VelocityktsEditField_9Label.BackgroundColor = [1 0 0];
                app.VelocityktsEditField_9.BackgroundColor = [1 0 0];
                app.VelocityktsEditField_9.Enable = 'off';
            elseif app.VelocityMachEditField_9.Value == 0
                app.VelocityktsEditField_9Label.BackgroundColor = [0.9412 0.9412 0.9412];
                app.VelocityktsEditField_9.BackgroundColor = [1 1 1];
                app.VelocityktsEditField_9.Enable = 'on';
            end
        end

        % Value changed function: TotalLandingRollftEditField
        function TotalLandingRollftEditFieldValueChanged(app, event)
            if app.TotalLandingRollftEditField.Value ~= 0
                app.TotalLandingDistanceftEditFieldLabel.BackgroundColor = [1 0 0];
                app.TotalLandingDistanceftEditField.BackgroundColor = [1 0 0];
                app.TotalLandingDistanceftEditField.Enable = 'off';
                app.TotalLandingDistanceftEditField.Value = 0;
            elseif app.TotalLandingRollftEditField.Value == 0
                app.TotalLandingDistanceftEditFieldLabel.BackgroundColor = [0.9412 0.9412 0.9412];
                app.TotalLandingDistanceftEditField.BackgroundColor = [1 1 1];
                app.TotalLandingDistanceftEditField.Enable = 'on';
            end
        end

        % Value changed function: TotalLandingDistanceftEditField
        function TotalLandingDistanceftEditFieldValueChanged(app, event)
            if app.TotalLandingDistanceftEditField.Value ~= 0
                app.TotalLandingRollftEditFieldLabel.BackgroundColor = [1 0 0];
                app.TotalLandingRollftEditField.BackgroundColor = [1 0 0];
                app.TotalLandingRollftEditField.Enable = 'off';
                app.TotalGroundRollftEditField.Value = 0;
            elseif app.TotalLandingDistanceftEditField.Value == 0
                app.TotalLandingRollftEditFieldLabel.BackgroundColor = [0.9412 0.9412 0.9412];
                app.TotalLandingRollftEditField.BackgroundColor = [1 1 1];
                app.TotalLandingRollftEditField.Enable = 'on';
            end
        end

        % Button pushed function: AddConstriaintButton
        function AddConstriaintButtonPushed(app, event)
            %Stall Button
            fid = fopen(app.FileNameEditField.Value,'a');
            writeVector = {'stall' app.RequiredorDesiredConstraintDropDown_9.Value app.EngineTypeDropDown_33.Value app.AfterburnerDropDown_33.Value app.AltitudeftEditField_8.Value app.VelocityktsEditField_8.Value app.VelocityMachEditField_8.Value app.TemperatureSLdegFEditField_5.Value app.EstimatedBetaEditField_8.Value NaN NaN NaN NaN NaN NaN NaN NaN NaN NaN NaN NaN NaN NaN NaN NaN NaN NaN NaN NaN NaN};
            fprintf(fid,'%s,%s,%s,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f\n',writeVector{1,:});
            fclose(fid);
            app.AltitudeftEditField_8.Value
        end

        % Button pushed function: AddConstriaintButton_2
        function AddConstriaintButton_2Pushed(app, event)
            fid = fopen(app.FileNameEditField.Value,'a');
writeVector = {'Yes' 'Test' 'Test' 0 NaN 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0};
fprintf(fid,'%s,%s,%s,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f\n',writeVector{1,:});
fclose(fid);
        end

        % Button pushed function: AddConstriaintButton_3
        function AddConstriaintButton_3Pushed(app, event)
           fid = fopen(app.FileNameEditField.Value,'a');
writeVector = {'Yes' 'Test' 'Test' 0 NaN 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0};
fprintf(fid,'%s,%s,%s,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f\n',writeVector{1,:});
fclose(fid); 
        end

        % Button pushed function: AddConstriaintButton_4
        function AddConstriaintButton_4Pushed(app, event)
            fid = fopen(app.FileNameEditField.Value,'a');
writeVector = {'Yes' 'Test' 'Test' 0 NaN 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0};
fprintf(fid,'%s,%s,%s,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f\n',writeVector{1,:});
fclose(fid);
        end

        % Button pushed function: AddConstriaintButton_5
        function AddConstriaintButton_5Pushed(app, event)
            fid = fopen(app.FileNameEditField.Value,'a');
writeVector = {'Yes' 'Test' 'Test' 0 NaN 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0};
fprintf(fid,'%s,%s,%s,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f\n',writeVector{1,:});
fclose(fid);
        end

        % Button pushed function: AddConstriaintButton_6
        function AddConstriaintButton_6Pushed(app, event)
            fid = fopen(app.FileNameEditField.Value,'a');
writeVector = {'Yes' 'Test' 'Test' 0 NaN 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0};
fprintf(fid,'%s,%s,%s,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f\n',writeVector{1,:});
fclose(fid);
        end

        % Button pushed function: AddConstriaintButton_7
        function AddConstriaintButton_7Pushed(app, event)
            fid = fopen(app.FileNameEditField.Value,'a');
writeVector = {'Yes' 'Test' 'Test' 0 NaN 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0};
fprintf(fid,'%s,%s,%s,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f\n',writeVector{1,:});
fclose(fid);
        end

        % Button pushed function: AddConstriaintButton_8
        function AddConstriaintButton_8Pushed(app, event)
           fid = fopen('Test.csv','a');
writeVector = {'Yes' 'Test' 'Test' 0 NaN 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0};
fprintf(fid,'%s,%s,%s,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f\n',writeVector{1,:});
fclose(fid); 
        end

        % Button pushed function: AddConstriaintButton_9
        function AddConstriaintButton_9Pushed(app, event)
            fid = fopen(app.FileNameEditField.Value,'a');
writeVector = {'Yes' 'Test' 'Test' 0 NaN 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0};
fprintf(fid,'%s,%s,%s,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f,%f\n',writeVector{1,:});
fclose(fid);
        end

        % Button pushed function: SaveNameButton
        function SaveNameButtonPushed(app, event)
            fid = fopen(app.FileNameEditField.Value,'w');
            nameVector = {'Requirement','Required_or_Desired','Engine_Type','Afterburner','Altitude_ft','Velocity_kts','Velocity_Mach','T0_SL','Estimated_B','Climb_Rate_Max_fpm','Load_Factor','Turn_Radius','V_Initial_kts','V_Initial_Mach','V_Final_kts','V_Final_Mach','Time_accel_sec','k_TO','Takeoff_Ground_Roll_ft','Takeoff_Total_Distance_ft','Runway_Friction_Coefficient','Time_rotate_sec','Angle_of_Climb_deg','Height_Obstacle_ft','Distance_Landing_Roll_ft','Distance_Total_Landing_ft','k_TD','k_obs','Time_Free_Roll_sec','Thrust_Rev_Perc_Thrust'};
            fprintf(fid,'%s,%s,%s,%s,%s,%s,%s,%s,%s,%s,%s,%s,%s,%s,%s,%s,%s,%s,%s,%s,%s,%s,%s,%s,%s,%s,%s,%s,%s,%s,%s,%s,%s,%s,%s\n',nameVector{1,:});
            fclose(fid);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ConstraintAnalysisInputsLabel = uilabel(app.UIFigure);
            app.ConstraintAnalysisInputsLabel.Text = 'Constraint Analysis Inputs';
            app.ConstraintValuesLabel = uilabel(app.UIFigure);
            app.ConstraintValuesLabel.Text = 'Constraint Values';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StallSpeedTab = uitab(app.TabGroup);
            app.StallSpeedTab.Title = 'Stall Speed';
            app.EnterStallParametersLabel = uilabel(app.StallSpeedTab);
            app.EnterStallParametersLabel.Text = 'Enter Stall Parameters:';
            app.RequiredorDesiredConstraintDropDown_9Label = uilabel(app.StallSpeedTab);
            app.RequiredorDesiredConstraintDropDown_9Label.Text = 'Required or Desired Constraint?';
            app.RequiredorDesiredConstraintDropDown_9 = uidropdown(app.StallSpeedTab);
            app.RequiredorDesiredConstraintDropDown_9.Items = {'required', 'desired'};
            app.RequiredorDesiredConstraintDropDown_9.Enable = 'off';
            app.RequiredorDesiredConstraintDropDown_9.Value = 'required';
            app.EngineTypeDropDown_33Label = uilabel(app.StallSpeedTab);
            app.EngineTypeDropDown_33Label.Text = 'Engine Type';
            app.EngineTypeDropDown_33 = uidropdown(app.StallSpeedTab);
            app.EngineTypeDropDown_33.Items = {'Turbojet', 'Turbofan'};
            app.EngineTypeDropDown_33.Enable = 'off';
            app.EngineTypeDropDown_33.Value = 'Turbojet';
            app.AfterburnerDropDown_33Label = uilabel(app.StallSpeedTab);
            app.AfterburnerDropDown_33Label.Text = 'Afterburner';
            app.AfterburnerDropDown_33 = uidropdown(app.StallSpeedTab);
            app.AfterburnerDropDown_33.Items = {'Yes', 'No'};
            app.AfterburnerDropDown_33.Enable = 'off';
            app.AfterburnerDropDown_33.Value = 'Yes';
            app.AltitudeftEditField_8Label = uilabel(app.StallSpeedTab);
            app.AltitudeftEditField_8Label.Text = 'Altitude [ft]';
            app.AltitudeftEditField_8 = uieditfield(app.StallSpeedTab, 'numeric');
            app.AltitudeftEditField_8.Enable = 'off';
            app.VelocityktsEditField_8Label = uilabel(app.StallSpeedTab);
            app.VelocityktsEditField_8Label.Text = 'Velocity [kts]';
            app.VelocityktsEditField_8 = uieditfield(app.StallSpeedTab, 'numeric');
            app.VelocityktsEditField_8.ValueChangedFcn = createCallbackFcn(app, @VelocityktsEditField_8ValueChanged, true);
            app.VelocityktsEditField_8.Enable = 'off';
            app.EstimatedBetaEditField_8Label = uilabel(app.StallSpeedTab);
            app.EstimatedBetaEditField_8Label.Text = 'Estimated Beta';
            app.EstimatedBetaEditField_8 = uieditfield(app.StallSpeedTab, 'numeric');
            app.EstimatedBetaEditField_8.Enable = 'off';
            app.VelocityMachEditField_8Label = uilabel(app.StallSpeedTab);
            app.VelocityMachEditField_8Label.Text = 'Velocity [Mach]';
            app.VelocityMachEditField_8 = uieditfield(app.StallSpeedTab, 'numeric');
            app.VelocityMachEditField_8.ValueChangedFcn = createCallbackFcn(app, @VelocityMachEditField_8ValueChanged, true);
            app.VelocityMachEditField_8.Enable = 'off';
            app.TemperatureSLdegFEditField_5Label = uilabel(app.StallSpeedTab);
            app.TemperatureSLdegFEditField_5Label.Text = 'Temperature SL [deg F]';
            app.TemperatureSLdegFEditField_5 = uieditfield(app.StallSpeedTab, 'numeric');
            app.TemperatureSLdegFEditField_5.Enable = 'off';
            app.AddConstriaintButton = uibutton(app.StallSpeedTab, 'push');
            app.AddConstriaintButton.ButtonPushedFcn = createCallbackFcn(app, @AddConstriaintButtonPushed, true);
            app.AddConstriaintButton.Enable = 'off';
            app.AddConstriaintButton.Text = 'Add Constriaint';
            app.TakeoffTab = uitab(app.TabGroup);
            app.TakeoffTab.Title = 'Takeoff';
            app.EnterTakeoffParametersLabel = uilabel(app.TakeoffTab);
            app.EnterTakeoffParametersLabel.Text = 'Enter Takeoff Parameters:';
            app.RequiredorDesiredConstraintDropDown_8Label = uilabel(app.TakeoffTab);
            app.RequiredorDesiredConstraintDropDown_8Label.Text = 'Required or Desired Constraint?';
            app.RequiredorDesiredConstraintDropDown_8 = uidropdown(app.TakeoffTab);
            app.RequiredorDesiredConstraintDropDown_8.Items = {'required', 'desired'};
            app.RequiredorDesiredConstraintDropDown_8.Enable = 'off';
            app.RequiredorDesiredConstraintDropDown_8.Value = 'required';
            app.EngineTypeDropDown_32Label = uilabel(app.TakeoffTab);
            app.EngineTypeDropDown_32Label.Text = 'Engine Type';
            app.EngineTypeDropDown_32 = uidropdown(app.TakeoffTab);
            app.EngineTypeDropDown_32.Items = {'Turbojet', 'Turbofan'};
            app.EngineTypeDropDown_32.Enable = 'off';
            app.EngineTypeDropDown_32.Value = 'Turbojet';
            app.AfterburnerDropDown_32Label = uilabel(app.TakeoffTab);
            app.AfterburnerDropDown_32Label.Text = 'Afterburner';
            app.AfterburnerDropDown_32 = uidropdown(app.TakeoffTab);
            app.AfterburnerDropDown_32.Items = {'Yes', 'No'};
            app.AfterburnerDropDown_32.Enable = 'off';
            app.AfterburnerDropDown_32.Value = 'Yes';
            app.AltitudeftEditField_7Label = uilabel(app.TakeoffTab);
            app.AltitudeftEditField_7Label.Text = 'Altitude [ft]';
            app.AltitudeftEditField_7 = uieditfield(app.TakeoffTab, 'numeric');
            app.AltitudeftEditField_7.Enable = 'off';
            app.ClimbRateftminEditFieldLabel = uilabel(app.TakeoffTab);
            app.ClimbRateftminEditFieldLabel.Text = 'Climb Rate [ft/min]';
            app.ClimbRateftminEditField = uieditfield(app.TakeoffTab, 'numeric');
            app.ClimbRateftminEditField.ValueChangedFcn = createCallbackFcn(app, @ClimbRateftminEditFieldValueChanged, true);
            app.ClimbRateftminEditField.Enable = 'off';
            app.EstimatedBetaEditField_7Label = uilabel(app.TakeoffTab);
            app.EstimatedBetaEditField_7Label.Text = 'Estimated Beta';
            app.EstimatedBetaEditField_7 = uieditfield(app.TakeoffTab, 'numeric');
            app.EstimatedBetaEditField_7.Enable = 'off';
            app.TakeoffVelocityMultiplierEditFieldLabel = uilabel(app.TakeoffTab);
            app.TakeoffVelocityMultiplierEditFieldLabel.Text = 'Takeoff Velocity Multiplier ';
            app.TakeoffVelocityMultiplierEditField = uieditfield(app.TakeoffTab, 'numeric');
            app.TakeoffVelocityMultiplierEditField.Enable = 'off';
            app.TemperatureSLdegFEditField_4Label = uilabel(app.TakeoffTab);
            app.TemperatureSLdegFEditField_4Label.Text = 'Temperature SL [deg F]';
            app.TemperatureSLdegFEditField_4 = uieditfield(app.TakeoffTab, 'numeric');
            app.TemperatureSLdegFEditField_4.Enable = 'off';
            app.TotalGroundRollftEditFieldLabel = uilabel(app.TakeoffTab);
            app.TotalGroundRollftEditFieldLabel.Text = 'Total Ground Roll [ft]';
            app.TotalGroundRollftEditField = uieditfield(app.TakeoffTab, 'numeric');
            app.TotalGroundRollftEditField.ValueChangedFcn = createCallbackFcn(app, @TotalGroundRollftEditFieldValueChanged, true);
            app.TotalGroundRollftEditField.Enable = 'off';
            app.TotalTakeoffDistanceftEditFieldLabel = uilabel(app.TakeoffTab);
            app.TotalTakeoffDistanceftEditFieldLabel.Text = 'Total Takeoff Distance [ft]';
            app.TotalTakeoffDistanceftEditField = uieditfield(app.TakeoffTab, 'numeric');
            app.TotalTakeoffDistanceftEditField.ValueChangedFcn = createCallbackFcn(app, @TotalTakeoffDistanceftEditFieldValueChanged, true);
            app.TotalTakeoffDistanceftEditField.Enable = 'off';
            app.HeightofObstacleftEditFieldLabel = uilabel(app.TakeoffTab);
            app.HeightofObstacleftEditFieldLabel.Text = 'Height of Obstacle [ft]';
            app.HeightofObstacleftEditField = uieditfield(app.TakeoffTab, 'numeric');
            app.HeightofObstacleftEditField.Enable = 'off';
            app.RotationTimesecLabel = uilabel(app.TakeoffTab);
            app.RotationTimesecLabel.Text = 'Rotation Time [sec]';
            app.RotationTimesecEditField = uieditfield(app.TakeoffTab, 'numeric');
            app.RotationTimesecEditField.Enable = 'off';
            app.RunwayFrictionCoefficientEditFieldLabel = uilabel(app.TakeoffTab);
            app.RunwayFrictionCoefficientEditFieldLabel.Text = 'Runway Friction Coefficient';
            app.RunwayFrictionCoefficientEditField = uieditfield(app.TakeoffTab, 'numeric');
            app.RunwayFrictionCoefficientEditField.Enable = 'off';
            app.AngleofClimbdegEditFieldLabel = uilabel(app.TakeoffTab);
            app.AngleofClimbdegEditFieldLabel.Text = 'Angle of Climb [deg]';
            app.AngleofClimbdegEditField = uieditfield(app.TakeoffTab, 'numeric');
            app.AngleofClimbdegEditField.ValueChangedFcn = createCallbackFcn(app, @AngleofClimbdegEditFieldValueChanged, true);
            app.AngleofClimbdegEditField.Enable = 'off';
            app.AddConstriaintButton_2 = uibutton(app.TakeoffTab, 'push');
            app.AddConstriaintButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddConstriaintButton_2Pushed, true);
            app.AddConstriaintButton_2.Text = 'Add Constriaint';
            app.HorizontalAccelerationTab = uitab(app.TabGroup);
            app.HorizontalAccelerationTab.Title = 'Horizontal Acceleration';
            app.EnterAccelParametersLabel = uilabel(app.HorizontalAccelerationTab);
            app.EnterAccelParametersLabel.Text = 'Enter Accel Parameters:';
            app.EngineTypeDropDown_14Label = uilabel(app.HorizontalAccelerationTab);
            app.EngineTypeDropDown_14Label.Text = 'Engine Type';
            app.EngineTypeDropDown_14 = uidropdown(app.HorizontalAccelerationTab);
            app.EngineTypeDropDown_14.Items = {'Turbojet', 'Turbofan'};
            app.EngineTypeDropDown_14.Enable = 'off';
            app.EngineTypeDropDown_14.Value = 'Turbojet';
            app.AfterburnerDropDown_14Label = uilabel(app.HorizontalAccelerationTab);
            app.AfterburnerDropDown_14Label.Text = 'Afterburner';
            app.AfterburnerDropDown_14 = uidropdown(app.HorizontalAccelerationTab);
            app.AfterburnerDropDown_14.Items = {'Yes', 'No'};
            app.AfterburnerDropDown_14.Enable = 'off';
            app.AfterburnerDropDown_14.Value = 'Yes';
            app.AltitudeftEditFieldLabel = uilabel(app.HorizontalAccelerationTab);
            app.AltitudeftEditFieldLabel.Text = 'Altitude [ft]';
            app.AltitudeftEditField = uieditfield(app.HorizontalAccelerationTab, 'numeric');
            app.AltitudeftEditField.Enable = 'off';
            app.InitialVelocityktsEditFieldLabel = uilabel(app.HorizontalAccelerationTab);
            app.InitialVelocityktsEditFieldLabel.Text = 'Initial Velocity [kts]';
            app.InitialVelocityktsEditField = uieditfield(app.HorizontalAccelerationTab, 'numeric');
            app.InitialVelocityktsEditField.ValueChangedFcn = createCallbackFcn(app, @InitialVelocityktsEditFieldValueChanged, true);
            app.InitialVelocityktsEditField.Enable = 'off';
            app.EstimatedBetaEditFieldLabel = uilabel(app.HorizontalAccelerationTab);
            app.EstimatedBetaEditFieldLabel.Text = 'Estimated Beta';
            app.EstimatedBetaEditField = uieditfield(app.HorizontalAccelerationTab, 'numeric');
            app.EstimatedBetaEditField.Enable = 'off';
            app.InitialVelocityMachEditFieldLabel = uilabel(app.HorizontalAccelerationTab);
            app.InitialVelocityMachEditFieldLabel.Text = 'Initial Velocity [Mach]';
            app.InitialVelocityMachEditField = uieditfield(app.HorizontalAccelerationTab, 'numeric');
            app.InitialVelocityMachEditField.ValueChangedFcn = createCallbackFcn(app, @InitialVelocityMachEditFieldValueChanged, true);
            app.InitialVelocityMachEditField.Enable = 'off';
            app.RequiredorDesiredConstraintDropDown_7Label = uilabel(app.HorizontalAccelerationTab);
            app.RequiredorDesiredConstraintDropDown_7Label.Text = 'Required or Desired Constraint?';
            app.RequiredorDesiredConstraintDropDown_7 = uidropdown(app.HorizontalAccelerationTab);
            app.RequiredorDesiredConstraintDropDown_7.Items = {'required', 'desired'};
            app.RequiredorDesiredConstraintDropDown_7.Enable = 'off';
            app.RequiredorDesiredConstraintDropDown_7.Value = 'required';
            app.TemperatureSLdegFEditField_3Label = uilabel(app.HorizontalAccelerationTab);
            app.TemperatureSLdegFEditField_3Label.Text = 'Temperature SL [deg F]';
            app.TemperatureSLdegFEditField_3 = uieditfield(app.HorizontalAccelerationTab, 'numeric');
            app.TemperatureSLdegFEditField_3.Enable = 'off';
            app.FinalVelocityktsEditFieldLabel = uilabel(app.HorizontalAccelerationTab);
            app.FinalVelocityktsEditFieldLabel.Text = 'Final Velocity [kts]';
            app.FinalVelocityktsEditField = uieditfield(app.HorizontalAccelerationTab, 'numeric');
            app.FinalVelocityktsEditField.ValueChangedFcn = createCallbackFcn(app, @FinalVelocityktsEditFieldValueChanged, true);
            app.FinalVelocityktsEditField.Enable = 'off';
            app.FinalVelocityMachEditFieldLabel = uilabel(app.HorizontalAccelerationTab);
            app.FinalVelocityMachEditFieldLabel.Text = 'Final Velocity [Mach]';
            app.FinalVelocityMachEditField = uieditfield(app.HorizontalAccelerationTab, 'numeric');
            app.FinalVelocityMachEditField.ValueChangedFcn = createCallbackFcn(app, @FinalVelocityMachEditFieldValueChanged, true);
            app.FinalVelocityMachEditField.Enable = 'off';
            app.TimetoAcceleratesecEditFieldLabel = uilabel(app.HorizontalAccelerationTab);
            app.TimetoAcceleratesecEditFieldLabel.Text = 'Time to Accelerate [sec]';
            app.TimetoAcceleratesecEditField = uieditfield(app.HorizontalAccelerationTab, 'numeric');
            app.TimetoAcceleratesecEditField.Enable = 'off';
            app.AddConstriaintButton_3 = uibutton(app.HorizontalAccelerationTab, 'push');
            app.AddConstriaintButton_3.ButtonPushedFcn = createCallbackFcn(app, @AddConstriaintButton_3Pushed, true);
            app.AddConstriaintButton_3.Text = 'Add Constriaint';
            app.ClimbTab = uitab(app.TabGroup);
            app.ClimbTab.Title = 'Climb';
            app.EnterClimbParametersLabel = uilabel(app.ClimbTab);
            app.EnterClimbParametersLabel.Text = 'Enter Climb Parameters:';
            app.RequiredorDesiredConstraintDropDown_6Label = uilabel(app.ClimbTab);
            app.RequiredorDesiredConstraintDropDown_6Label.Text = 'Required or Desired Constraint?';
            app.RequiredorDesiredConstraintDropDown_6 = uidropdown(app.ClimbTab);
            app.RequiredorDesiredConstraintDropDown_6.Items = {'required', 'desired'};
            app.RequiredorDesiredConstraintDropDown_6.Enable = 'off';
            app.RequiredorDesiredConstraintDropDown_6.Value = 'required';
            app.EngineTypeDropDown_27Label = uilabel(app.ClimbTab);
            app.EngineTypeDropDown_27Label.Text = 'Engine Type';
            app.EngineTypeDropDown_27 = uidropdown(app.ClimbTab);
            app.EngineTypeDropDown_27.Items = {'Turbojet', 'Turbofan'};
            app.EngineTypeDropDown_27.Enable = 'off';
            app.EngineTypeDropDown_27.Value = 'Turbojet';
            app.AfterburnerDropDown_27Label = uilabel(app.ClimbTab);
            app.AfterburnerDropDown_27Label.Text = 'Afterburner';
            app.AfterburnerDropDown_27 = uidropdown(app.ClimbTab);
            app.AfterburnerDropDown_27.Items = {'Yes', 'No'};
            app.AfterburnerDropDown_27.Enable = 'off';
            app.AfterburnerDropDown_27.Value = 'Yes';
            app.AltitudeftEditField_2Label = uilabel(app.ClimbTab);
            app.AltitudeftEditField_2Label.Text = 'Altitude [ft]';
            app.AltitudeftEditField_2 = uieditfield(app.ClimbTab, 'numeric');
            app.AltitudeftEditField_2.Enable = 'off';
            app.VelocityktsEditField_2Label = uilabel(app.ClimbTab);
            app.VelocityktsEditField_2Label.Text = 'Velocity [kts]';
            app.VelocityktsEditField_2 = uieditfield(app.ClimbTab, 'numeric');
            app.VelocityktsEditField_2.ValueChangedFcn = createCallbackFcn(app, @VelocityktsEditField_2ValueChanged, true);
            app.VelocityktsEditField_2.Enable = 'off';
            app.EstimatedBetaEditField_2Label = uilabel(app.ClimbTab);
            app.EstimatedBetaEditField_2Label.Text = 'Estimated Beta';
            app.EstimatedBetaEditField_2 = uieditfield(app.ClimbTab, 'numeric');
            app.EstimatedBetaEditField_2.Enable = 'off';
            app.VelocityMachEditField_2Label = uilabel(app.ClimbTab);
            app.VelocityMachEditField_2Label.Text = 'Velocity [Mach]';
            app.VelocityMachEditField_2 = uieditfield(app.ClimbTab, 'numeric');
            app.VelocityMachEditField_2.ValueChangedFcn = createCallbackFcn(app, @VelocityMachEditField_2ValueChanged, true);
            app.VelocityMachEditField_2.Enable = 'off';
            app.TemperatureSLdegFEditField_2Label = uilabel(app.ClimbTab);
            app.TemperatureSLdegFEditField_2Label.Text = 'Temperature SL [deg F]';
            app.TemperatureSLdegFEditField_2 = uieditfield(app.ClimbTab, 'numeric');
            app.TemperatureSLdegFEditField_2.Enable = 'off';
            app.ClimbRateftminEditField_2Label = uilabel(app.ClimbTab);
            app.ClimbRateftminEditField_2Label.Text = 'Climb Rate [ft/min]';
            app.ClimbRateftminEditField_2 = uieditfield(app.ClimbTab, 'numeric');
            app.ClimbRateftminEditField_2.Enable = 'off';
            app.AddConstriaintButton_4 = uibutton(app.ClimbTab, 'push');
            app.AddConstriaintButton_4.ButtonPushedFcn = createCallbackFcn(app, @AddConstriaintButton_4Pushed, true);
            app.AddConstriaintButton_4.Text = 'Add Constriaint';
            app.CruiseTab = uitab(app.TabGroup);
            app.CruiseTab.Title = 'Cruise';
            app.EnterCruiseParametersLabel = uilabel(app.CruiseTab);
            app.EnterCruiseParametersLabel.Text = 'Enter Cruise Parameters:';
            app.RequiredorDesiredConstraintDropDown_5Label = uilabel(app.CruiseTab);
            app.RequiredorDesiredConstraintDropDown_5Label.Text = 'Required or Desired Constraint?';
            app.RequiredorDesiredConstraintDropDown_5 = uidropdown(app.CruiseTab);
            app.RequiredorDesiredConstraintDropDown_5.Items = {'required', 'desired'};
            app.RequiredorDesiredConstraintDropDown_5.Enable = 'off';
            app.RequiredorDesiredConstraintDropDown_5.Value = 'required';
            app.EngineTypeDropDown_28Label = uilabel(app.CruiseTab);
            app.EngineTypeDropDown_28Label.Text = 'Engine Type';
            app.EngineTypeDropDown_28 = uidropdown(app.CruiseTab);
            app.EngineTypeDropDown_28.Items = {'Turbojet', 'Turbofan'};
            app.EngineTypeDropDown_28.Enable = 'off';
            app.EngineTypeDropDown_28.Value = 'Turbojet';
            app.AfterburnerDropDown_28Label = uilabel(app.CruiseTab);
            app.AfterburnerDropDown_28Label.Text = 'Afterburner';
            app.AfterburnerDropDown_28 = uidropdown(app.CruiseTab);
            app.AfterburnerDropDown_28.Items = {'Yes', 'No'};
            app.AfterburnerDropDown_28.Enable = 'off';
            app.AfterburnerDropDown_28.Value = 'Yes';
            app.AltitudeftEditField_3Label = uilabel(app.CruiseTab);
            app.AltitudeftEditField_3Label.Text = 'Altitude [ft]';
            app.AltitudeftEditField_3 = uieditfield(app.CruiseTab, 'numeric');
            app.AltitudeftEditField_3.Enable = 'off';
            app.VelocityktsEditField_3Label = uilabel(app.CruiseTab);
            app.VelocityktsEditField_3Label.Text = 'Velocity [kts]';
            app.VelocityktsEditField_3 = uieditfield(app.CruiseTab, 'numeric');
            app.VelocityktsEditField_3.ValueChangedFcn = createCallbackFcn(app, @VelocityktsEditField_3ValueChanged, true);
            app.VelocityktsEditField_3.Enable = 'off';
            app.EstimatedBetaEditField_3Label = uilabel(app.CruiseTab);
            app.EstimatedBetaEditField_3Label.Text = 'Estimated Beta';
            app.EstimatedBetaEditField_3 = uieditfield(app.CruiseTab, 'numeric');
            app.EstimatedBetaEditField_3.Enable = 'off';
            app.VelocityMachEditField_3Label = uilabel(app.CruiseTab);
            app.VelocityMachEditField_3Label.Text = 'Velocity [Mach]';
            app.VelocityMachEditField_3 = uieditfield(app.CruiseTab, 'numeric');
            app.VelocityMachEditField_3.ValueChangedFcn = createCallbackFcn(app, @VelocityMachEditField_3ValueChanged, true);
            app.VelocityMachEditField_3.Enable = 'off';
            app.TemperatureSLdegFEditFieldLabel = uilabel(app.CruiseTab);
            app.TemperatureSLdegFEditFieldLabel.Text = 'Temperature SL [deg F]';
            app.TemperatureSLdegFEditField = uieditfield(app.CruiseTab, 'numeric');
            app.TemperatureSLdegFEditField.Enable = 'off';
            app.AddConstriaintButton_5 = uibutton(app.CruiseTab, 'push');
            app.AddConstriaintButton_5.ButtonPushedFcn = createCallbackFcn(app, @AddConstriaintButton_5Pushed, true);
            app.AddConstriaintButton_5.Text = 'Add Constriaint';
            app.DashTab = uitab(app.TabGroup);
            app.DashTab.Title = 'Dash';
            app.EnterDashParametersLabel = uilabel(app.DashTab);
            app.EnterDashParametersLabel.Text = 'Enter Dash Parameters:';
            app.RequiredorDesiredConstraintDropDown_4Label = uilabel(app.DashTab);
            app.RequiredorDesiredConstraintDropDown_4Label.Text = 'Required or Desired Constraint?';
            app.RequiredorDesiredConstraintDropDown_4 = uidropdown(app.DashTab);
            app.RequiredorDesiredConstraintDropDown_4.Items = {'required', 'desired'};
            app.RequiredorDesiredConstraintDropDown_4.Enable = 'off';
            app.RequiredorDesiredConstraintDropDown_4.Value = 'required';
            app.EngineTypeDropDown_29Label = uilabel(app.DashTab);
            app.EngineTypeDropDown_29Label.Text = 'Engine Type';
            app.EngineTypeDropDown_29 = uidropdown(app.DashTab);
            app.EngineTypeDropDown_29.Items = {'Turbojet', 'Turbofan'};
            app.EngineTypeDropDown_29.Enable = 'off';
            app.EngineTypeDropDown_29.Value = 'Turbojet';
            app.AfterburnerDropDown_29Label = uilabel(app.DashTab);
            app.AfterburnerDropDown_29Label.Text = 'Afterburner';
            app.AfterburnerDropDown_29 = uidropdown(app.DashTab);
            app.AfterburnerDropDown_29.Items = {'Yes', 'No'};
            app.AfterburnerDropDown_29.Enable = 'off';
            app.AfterburnerDropDown_29.Value = 'Yes';
            app.AltitudeftEditField_4Label = uilabel(app.DashTab);
            app.AltitudeftEditField_4Label.Text = 'Altitude [ft]';
            app.AltitudeftEditField_4 = uieditfield(app.DashTab, 'numeric');
            app.AltitudeftEditField_4.Enable = 'off';
            app.VelocityktsEditField_4Label = uilabel(app.DashTab);
            app.VelocityktsEditField_4Label.Text = 'Velocity [kts]';
            app.VelocityktsEditField_4 = uieditfield(app.DashTab, 'numeric');
            app.VelocityktsEditField_4.ValueChangedFcn = createCallbackFcn(app, @VelocityktsEditField_4ValueChanged, true);
            app.VelocityktsEditField_4.Enable = 'off';
            app.EstimatedBetaEditField_4Label = uilabel(app.DashTab);
            app.EstimatedBetaEditField_4Label.Text = 'Estimated Beta';
            app.EstimatedBetaEditField_4 = uieditfield(app.DashTab, 'numeric');
            app.EstimatedBetaEditField_4.Enable = 'off';
            app.VelocityMachEditField_4Label = uilabel(app.DashTab);
            app.VelocityMachEditField_4Label.Text = 'Velocity [Mach]';
            app.VelocityMachEditField_4 = uieditfield(app.DashTab, 'numeric');
            app.VelocityMachEditField_4.ValueChangedFcn = createCallbackFcn(app, @VelocityMachEditField_4ValueChanged, true);
            app.VelocityMachEditField_4.Enable = 'off';
            app.TemperatureSLdegFEditField_6Label = uilabel(app.DashTab);
            app.TemperatureSLdegFEditField_6Label.Text = 'Temperature SL [deg F]';
            app.TemperatureSLdegFEditField_6 = uieditfield(app.DashTab, 'numeric');
            app.TemperatureSLdegFEditField_6.Enable = 'off';
            app.AddConstriaintButton_6 = uibutton(app.DashTab, 'push');
            app.AddConstriaintButton_6.ButtonPushedFcn = createCallbackFcn(app, @AddConstriaintButton_6Pushed, true);
            app.AddConstriaintButton_6.Text = 'Add Constriaint';
            app.TurnTab = uitab(app.TabGroup);
            app.TurnTab.Title = 'Turn';
            app.EnterTurnParametersLabel = uilabel(app.TurnTab);
            app.EnterTurnParametersLabel.Text = 'Enter Turn Parameters:';
            app.RequiredorDesiredConstraintDropDown_3Label = uilabel(app.TurnTab);
            app.RequiredorDesiredConstraintDropDown_3Label.Text = 'Required or Desired Constraint?';
            app.RequiredorDesiredConstraintDropDown_3 = uidropdown(app.TurnTab);
            app.RequiredorDesiredConstraintDropDown_3.Items = {'required', 'desired'};
            app.RequiredorDesiredConstraintDropDown_3.Enable = 'off';
            app.RequiredorDesiredConstraintDropDown_3.Value = 'required';
            app.EngineTypeDropDown_30Label = uilabel(app.TurnTab);
            app.EngineTypeDropDown_30Label.Text = 'Engine Type';
            app.EngineTypeDropDown_30 = uidropdown(app.TurnTab);
            app.EngineTypeDropDown_30.Items = {'Turbojet', 'Turbofan'};
            app.EngineTypeDropDown_30.Enable = 'off';
            app.EngineTypeDropDown_30.Value = 'Turbojet';
            app.AfterburnerDropDown_30Label = uilabel(app.TurnTab);
            app.AfterburnerDropDown_30Label.Text = 'Afterburner';
            app.AfterburnerDropDown_30 = uidropdown(app.TurnTab);
            app.AfterburnerDropDown_30.Items = {'Yes', 'No'};
            app.AfterburnerDropDown_30.Enable = 'off';
            app.AfterburnerDropDown_30.Value = 'Yes';
            app.AltitudeftEditField_5Label = uilabel(app.TurnTab);
            app.AltitudeftEditField_5Label.Text = 'Altitude [ft]';
            app.AltitudeftEditField_5 = uieditfield(app.TurnTab, 'numeric');
            app.AltitudeftEditField_5.Enable = 'off';
            app.VelocityktsEditField_5Label = uilabel(app.TurnTab);
            app.VelocityktsEditField_5Label.Text = 'Velocity [kts]';
            app.VelocityktsEditField_5 = uieditfield(app.TurnTab, 'numeric');
            app.VelocityktsEditField_5.ValueChangedFcn = createCallbackFcn(app, @VelocityktsEditField_5ValueChanged, true);
            app.VelocityktsEditField_5.Enable = 'off';
            app.EstimatedBetaEditField_5Label = uilabel(app.TurnTab);
            app.EstimatedBetaEditField_5Label.Text = 'Estimated Beta';
            app.EstimatedBetaEditField_5 = uieditfield(app.TurnTab, 'numeric');
            app.EstimatedBetaEditField_5.Enable = 'off';
            app.VelocityMachEditField_5Label = uilabel(app.TurnTab);
            app.VelocityMachEditField_5Label.Text = 'Velocity [Mach]';
            app.VelocityMachEditField_5 = uieditfield(app.TurnTab, 'numeric');
            app.VelocityMachEditField_5.ValueChangedFcn = createCallbackFcn(app, @VelocityMachEditField_5ValueChanged, true);
            app.VelocityMachEditField_5.Enable = 'off';
            app.TemperatureSLdegFEditField_7Label = uilabel(app.TurnTab);
            app.TemperatureSLdegFEditField_7Label.Text = 'Temperature SL [deg F]';
            app.TemperatureSLdegFEditField_7 = uieditfield(app.TurnTab, 'numeric');
            app.TemperatureSLdegFEditField_7.Enable = 'off';
            app.LoadFactorEditFieldLabel = uilabel(app.TurnTab);
            app.LoadFactorEditFieldLabel.Text = 'Load Factor';
            app.LoadFactorEditField = uieditfield(app.TurnTab, 'numeric');
            app.LoadFactorEditField.ValueChangedFcn = createCallbackFcn(app, @LoadFactorEditFieldValueChanged, true);
            app.LoadFactorEditField.Enable = 'off';
            app.TurnRadiusftEditFieldLabel = uilabel(app.TurnTab);
            app.TurnRadiusftEditFieldLabel.Text = 'Turn Radius [ft]';
            app.TurnRadiusftEditField = uieditfield(app.TurnTab, 'numeric');
            app.TurnRadiusftEditField.ValueChangedFcn = createCallbackFcn(app, @TurnRadiusftEditFieldValueChanged, true);
            app.TurnRadiusftEditField.Enable = 'off';
            app.AddConstriaintButton_7 = uibutton(app.TurnTab, 'push');
            app.AddConstriaintButton_7.ButtonPushedFcn = createCallbackFcn(app, @AddConstriaintButton_7Pushed, true);
            app.AddConstriaintButton_7.Text = 'Add Constriaint';
            app.CeilingTab = uitab(app.TabGroup);
            app.CeilingTab.Title = 'Ceiling';
            app.EnterCeilingParametersLabel = uilabel(app.CeilingTab);
            app.EnterCeilingParametersLabel.Text = 'Enter Ceiling Parameters:';
            app.RequiredorDesiredConstraintDropDownLabel = uilabel(app.CeilingTab);
            app.RequiredorDesiredConstraintDropDownLabel.Text = 'Required or Desired Constraint?';
            app.RequiredorDesiredConstraintDropDown = uidropdown(app.CeilingTab);
            app.RequiredorDesiredConstraintDropDown.Items = {'required', 'desired'};
            app.RequiredorDesiredConstraintDropDown.Enable = 'off';
            app.RequiredorDesiredConstraintDropDown.Value = 'required';
            app.EngineTypeDropDown_31Label = uilabel(app.CeilingTab);
            app.EngineTypeDropDown_31Label.Text = 'Engine Type';
            app.EngineTypeDropDown_31 = uidropdown(app.CeilingTab);
            app.EngineTypeDropDown_31.Items = {'Turbojet', 'Turbofan'};
            app.EngineTypeDropDown_31.Enable = 'off';
            app.EngineTypeDropDown_31.Value = 'Turbojet';
            app.AfterburnerDropDown_31Label = uilabel(app.CeilingTab);
            app.AfterburnerDropDown_31Label.Text = 'Afterburner';
            app.AfterburnerDropDown_31 = uidropdown(app.CeilingTab);
            app.AfterburnerDropDown_31.Items = {'Yes', 'No'};
            app.AfterburnerDropDown_31.Enable = 'off';
            app.AfterburnerDropDown_31.Value = 'Yes';
            app.AltitudeftEditField_6Label = uilabel(app.CeilingTab);
            app.AltitudeftEditField_6Label.Text = 'Altitude [ft]';
            app.AltitudeftEditField_6 = uieditfield(app.CeilingTab, 'numeric');
            app.AltitudeftEditField_6.Enable = 'off';
            app.VelocityktsEditField_6Label = uilabel(app.CeilingTab);
            app.VelocityktsEditField_6Label.Text = 'Velocity [kts]';
            app.VelocityktsEditField_6 = uieditfield(app.CeilingTab, 'numeric');
            app.VelocityktsEditField_6.ValueChangedFcn = createCallbackFcn(app, @VelocityktsEditField_6ValueChanged, true);
            app.VelocityktsEditField_6.Enable = 'off';
            app.EstimatedBetaEditField_6Label = uilabel(app.CeilingTab);
            app.EstimatedBetaEditField_6Label.Text = 'Estimated Beta';
            app.EstimatedBetaEditField_6 = uieditfield(app.CeilingTab, 'numeric');
            app.EstimatedBetaEditField_6.Enable = 'off';
            app.VelocityMachEditField_6Label = uilabel(app.CeilingTab);
            app.VelocityMachEditField_6Label.Text = 'Velocity [Mach]';
            app.VelocityMachEditField_6 = uieditfield(app.CeilingTab, 'numeric');
            app.VelocityMachEditField_6.ValueChangedFcn = createCallbackFcn(app, @VelocityMachEditField_6ValueChanged, true);
            app.VelocityMachEditField_6.Enable = 'off';
            app.TemperatureSLdegFEditField_8Label = uilabel(app.CeilingTab);
            app.TemperatureSLdegFEditField_8Label.Text = 'Temperature SL [deg F]';
            app.TemperatureSLdegFEditField_8 = uieditfield(app.CeilingTab, 'numeric');
            app.TemperatureSLdegFEditField_8.Enable = 'off';
            app.ClimbRateftminEditField_3Label = uilabel(app.CeilingTab);
            app.ClimbRateftminEditField_3Label.Text = 'Climb Rate [ft/min]';
            app.ClimbRateftminEditField_3 = uieditfield(app.CeilingTab, 'numeric');
            app.ClimbRateftminEditField_3.Enable = 'off';
            app.AddConstriaintButton_8 = uibutton(app.CeilingTab, 'push');
            app.AddConstriaintButton_8.ButtonPushedFcn = createCallbackFcn(app, @AddConstriaintButton_8Pushed, true);
            app.AddConstriaintButton_8.Text = 'Add Constriaint';
            app.LandingTab = uitab(app.TabGroup);
            app.LandingTab.Title = 'Landing';
            app.EnterLandingParametersLabel = uilabel(app.LandingTab);
            app.EnterLandingParametersLabel.Text = 'Enter Landing Parameters:';
            app.RequiredorDesiredConstraintDropDown_2Label = uilabel(app.LandingTab);
            app.RequiredorDesiredConstraintDropDown_2Label.Text = 'Required or Desired Constraint?';
            app.RequiredorDesiredConstraintDropDown_2 = uidropdown(app.LandingTab);
            app.RequiredorDesiredConstraintDropDown_2.Items = {'required', 'desired'};
            app.RequiredorDesiredConstraintDropDown_2.Enable = 'off';
            app.RequiredorDesiredConstraintDropDown_2.Value = 'required';
            app.EngineTypeDropDown_34Label = uilabel(app.LandingTab);
            app.EngineTypeDropDown_34Label.Text = 'Engine Type';
            app.EngineTypeDropDown_34 = uidropdown(app.LandingTab);
            app.EngineTypeDropDown_34.Items = {'Turbojet', 'Turbofan'};
            app.EngineTypeDropDown_34.Enable = 'off';
            app.EngineTypeDropDown_34.Value = 'Turbojet';
            app.AfterburnerDropDown_34Label = uilabel(app.LandingTab);
            app.AfterburnerDropDown_34Label.Text = 'Afterburner';
            app.AfterburnerDropDown_34 = uidropdown(app.LandingTab);
            app.AfterburnerDropDown_34.Items = {'Yes', 'No'};
            app.AfterburnerDropDown_34.Enable = 'off';
            app.AfterburnerDropDown_34.Value = 'Yes';
            app.AltitudeftEditField_9Label = uilabel(app.LandingTab);
            app.AltitudeftEditField_9Label.Text = 'Altitude [ft]';
            app.AltitudeftEditField_9 = uieditfield(app.LandingTab, 'numeric');
            app.AltitudeftEditField_9.Enable = 'off';
            app.VelocityktsEditField_9Label = uilabel(app.LandingTab);
            app.VelocityktsEditField_9Label.Text = 'Velocity [kts]';
            app.VelocityktsEditField_9 = uieditfield(app.LandingTab, 'numeric');
            app.VelocityktsEditField_9.ValueChangedFcn = createCallbackFcn(app, @VelocityktsEditField_9ValueChanged, true);
            app.VelocityktsEditField_9.Enable = 'off';
            app.EstimatedBetaEditField_9Label = uilabel(app.LandingTab);
            app.EstimatedBetaEditField_9Label.Text = 'Estimated Beta';
            app.EstimatedBetaEditField_9 = uieditfield(app.LandingTab, 'numeric');
            app.EstimatedBetaEditField_9.Enable = 'off';
            app.VelocityMachEditField_9Label = uilabel(app.LandingTab);
            app.VelocityMachEditField_9Label.Text = 'Velocity [Mach]';
            app.VelocityMachEditField_9 = uieditfield(app.LandingTab, 'numeric');
            app.VelocityMachEditField_9.ValueChangedFcn = createCallbackFcn(app, @VelocityMachEditField_9ValueChanged, true);
            app.VelocityMachEditField_9.Enable = 'off';
            app.TemperatureSLdegFEditField_9Label = uilabel(app.LandingTab);
            app.TemperatureSLdegFEditField_9Label.Text = 'Temperature SL [deg F]';
            app.TemperatureSLdegFEditField_9 = uieditfield(app.LandingTab, 'numeric');
            app.TemperatureSLdegFEditField_9.Enable = 'off';
            app.HeightofObstacleftEditField_2Label = uilabel(app.LandingTab);
            app.HeightofObstacleftEditField_2Label.Text = 'Height of Obstacle [ft]';
            app.HeightofObstacleftEditField_2 = uieditfield(app.LandingTab, 'numeric');
            app.HeightofObstacleftEditField_2.Enable = 'off';
            app.FreeRollTimesecEditFieldLabel = uilabel(app.LandingTab);
            app.FreeRollTimesecEditFieldLabel.Text = 'Free Roll Time [sec]';
            app.FreeRollTimesecEditField = uieditfield(app.LandingTab, 'numeric');
            app.FreeRollTimesecEditField.Enable = 'off';
            app.RunwayFrictionCoefficientEditField_2Label = uilabel(app.LandingTab);
            app.RunwayFrictionCoefficientEditField_2Label.Text = 'Runway Friction Coefficient';
            app.RunwayFrictionCoefficientEditField_2 = uieditfield(app.LandingTab, 'numeric');
            app.RunwayFrictionCoefficientEditField_2.Enable = 'off';
            app.ThrustReversersThrustEditFieldLabel = uilabel(app.LandingTab);
            app.ThrustReversersThrustEditFieldLabel.Text = 'Thrust Reversers [% Thrust]';
            app.ThrustReversersThrustEditField = uieditfield(app.LandingTab, 'numeric');
            app.ThrustReversersThrustEditField.Enable = 'off';
            app.TotalLandingRollftEditFieldLabel = uilabel(app.LandingTab);
            app.TotalLandingRollftEditFieldLabel.Text = 'Total Landing Roll [ft]';
            app.TotalLandingRollftEditField = uieditfield(app.LandingTab, 'numeric');
            app.TotalLandingRollftEditField.ValueChangedFcn = createCallbackFcn(app, @TotalLandingRollftEditFieldValueChanged, true);
            app.TotalLandingRollftEditField.Enable = 'off';
            app.TotalLandingDistanceftEditFieldLabel = uilabel(app.LandingTab);
            app.TotalLandingDistanceftEditFieldLabel.Text = 'Total Landing Distance [ft]';
            app.TotalLandingDistanceftEditField = uieditfield(app.LandingTab, 'numeric');
            app.TotalLandingDistanceftEditField.ValueChangedFcn = createCallbackFcn(app, @TotalLandingDistanceftEditFieldValueChanged, true);
            app.TotalLandingDistanceftEditField.Enable = 'off';
            app.TouchdownVelocityMultiplierEditFieldLabel = uilabel(app.LandingTab);
            app.TouchdownVelocityMultiplierEditFieldLabel.Text = 'Touchdown Velocity Multiplier';
            app.TouchdownVelocityMultiplierEditField = uieditfield(app.LandingTab, 'numeric');
            app.TouchdownVelocityMultiplierEditField.Enable = 'off';
            app.ObstacleVelocityMultiplierEditFieldLabel = uilabel(app.LandingTab);
            app.ObstacleVelocityMultiplierEditFieldLabel.Text = 'Obstacle Velocity Multiplier';
            app.ObstacleVelocityMultiplierEditField = uieditfield(app.LandingTab, 'numeric');
            app.ObstacleVelocityMultiplierEditField.Enable = 'off';
            app.AddConstriaintButton_9 = uibutton(app.LandingTab, 'push');
            app.AddConstriaintButton_9.ButtonPushedFcn = createCallbackFcn(app, @AddConstriaintButton_9Pushed, true);
            app.AddConstriaintButton_9.Text = 'Add Constriaint';
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.ChooseConstraintsLabel = uilabel(app.UIFigure);
            app.ChooseConstraintsLabel.Text = 'Choose Constraints';
            app.StallSpeedCheckBox = uicheckbox(app.UIFigure);
            app.StallSpeedCheckBox.ValueChangedFcn = createCallbackFcn(app, @StallSpeedCheckBoxValueChanged, true);
            app.StallSpeedCheckBox.Text = 'Stall Speed';
            app.TakeoffCheckBox = uicheckbox(app.UIFigure);
            app.TakeoffCheckBox.ValueChangedFcn = createCallbackFcn(app, @TakeoffCheckBoxValueChanged, true);
            app.TakeoffCheckBox.Text = 'Takeoff';
            app.HorizontalAccelerationCheckBox = uicheckbox(app.UIFigure);
            app.HorizontalAccelerationCheckBox.ValueChangedFcn = createCallbackFcn(app, @HorizontalAccelerationCheckBoxValueChanged, true);
            app.HorizontalAccelerationCheckBox.Text = 'Horizontal Acceleration';
            app.ClimbCheckBox = uicheckbox(app.UIFigure);
            app.ClimbCheckBox.ValueChangedFcn = createCallbackFcn(app, @ClimbCheckBoxValueChanged, true);
            app.ClimbCheckBox.Text = 'Climb';
            app.CruiseCheckBox = uicheckbox(app.UIFigure);
            app.CruiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @CruiseCheckBoxValueChanged, true);
            app.CruiseCheckBox.Text = 'Cruise';
            app.DashCheckBox = uicheckbox(app.UIFigure);
            app.DashCheckBox.ValueChangedFcn = createCallbackFcn(app, @DashCheckBoxValueChanged, true);
            app.DashCheckBox.Text = 'Dash';
            app.TurnCheckBox = uicheckbox(app.UIFigure);
            app.TurnCheckBox.ValueChangedFcn = createCallbackFcn(app, @TurnCheckBoxValueChanged, true);
            app.TurnCheckBox.Text = 'Turn';
            app.CeilingCheckBox = uicheckbox(app.UIFigure);
            app.CeilingCheckBox.ValueChangedFcn = createCallbackFcn(app, @CeilingCheckBoxValueChanged, true);
            app.CeilingCheckBox.Text = 'Ceiling';
            app.LandingCheckBox = uicheckbox(app.UIFigure);
            app.LandingCheckBox.ValueChangedFcn = createCallbackFcn(app, @LandingCheckBoxValueChanged, true);
            app.LandingCheckBox.Text = 'Landing';
            app.FileNameLabel = uilabel(app.UIFigure);
            app.FileNameLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.SaveNameButton = uibutton(app.UIFigure, 'push');
            app.SaveNameButton.ButtonPushedFcn = createCallbackFcn(app, @SaveNameButtonPushed, true);
            app.SaveNameButton.Text = 'Save Name';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ConstraintGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end