classdef SizingSynthesisMaster < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SizingandSynthesisToolLabel    matlab.ui.control.Label
        NameofVehicleEditFieldLabel    matlab.ui.control.Label
        NameofVehicleEditField         matlab.ui.control.EditField
        MissionAnalysisButton          matlab.ui.control.Button
        ConstraintAnalysisButton       matlab.ui.control.Button
        RunCaseButton                  matlab.ui.control.Button
        CLmaxEditFieldLabel            matlab.ui.control.Label
        CLmaxEditField                 matlab.ui.control.NumericEditField
        AerodynamicDataLabel           matlab.ui.control.Label
        CLalphaEditFieldLabel          matlab.ui.control.Label
        CLalphaEditField               matlab.ui.control.NumericEditField
        CL0EditFieldLabel              matlab.ui.control.Label
        CL0EditField                   matlab.ui.control.NumericEditField
        CD0EditFieldLabel              matlab.ui.control.Label
        CD0EditField                   matlab.ui.control.NumericEditField
        CDRTakeoffEditFieldLabel       matlab.ui.control.Label
        CDRTakeoffEditField            matlab.ui.control.NumericEditField
        CDRLandingEditFieldLabel       matlab.ui.control.Label
        CDRLandingEditField            matlab.ui.control.NumericEditField
        InterferenceDragCoefficientK2EditFieldLabel  matlab.ui.control.Label
        InterferenceDragCoefficientK2EditField  matlab.ui.control.NumericEditField
        AspectRatioEditFieldLabel      matlab.ui.control.Label
        AspectRatioEditField           matlab.ui.control.NumericEditField
        OswaldsEfficiencyEditFieldLabel  matlab.ui.control.Label
        OswaldsEfficiencyEditField     matlab.ui.control.NumericEditField
        MissionFileNameEditFieldLabel  matlab.ui.control.Label
        MissionFileNameEditField       matlab.ui.control.EditField
        LoadMissionButton              matlab.ui.control.Button
        LoadRequirementsButton         matlab.ui.control.Button
        ConstraintFileNameEditFieldLabel  matlab.ui.control.Label
        ConstraintFileNameEditField    matlab.ui.control.EditField
        LoadAeroDataButton             matlab.ui.control.Button
        AeroFileNameEditFieldLabel     matlab.ui.control.Label
        AeroFileNameEditField          matlab.ui.control.EditField
        OnlyloadfilesiftheyaretheproperformatLabel  matlab.ui.control.Label
        SamanthaCorradoandNathanCraneLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        ConstraintGUI
        aero
        reqs
        mission
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.aero.CLmax = 0;
            app.reqs.general.size = 0;
            app.mission = 0;
        end

        % Button pushed function: ConstraintAnalysisButton
        function ConstraintAnalysisButtonPushed(app, event)
            app.ConstriantGUI = ConstriantGUI(app, app.NameofVehicleEditField.Value);
        end

        % Button pushed function: LoadAeroDataButton
        function LoadAeroDataButtonPushed(app, event)
            try
                app.aero = readAeroExcel(app.AeroFileNameEditField.Value);
                app.LoadAeroDataButton.BackgroundColor = [0 1 0];
                pause(1)
                app.LoadAeroDataButton.BackgroundColor = [0.96 0.96 0.96];
            catch aeroFileError
                errorMessage = ['Unable to open file ''',app.AeroFileNameEditField.Value,'.txt''.'];
                errorMessage2 = ['Unable to open file ''',app.AeroFileNameEditField.Value,'''.'];
                if (strcmp(aeroFileError.message,errorMessage)) || (strcmp(aeroFileError.message,errorMessage2))
                    app.LoadAeroDataButton.BackgroundColor = [1 0 0];
                    pause(1)
                    app.LoadAeroDataButton.BackgroundColor = [0.96 0.96 0.96];
                end
            end
        end

        % Button pushed function: LoadMissionButton
        function LoadMissionButtonPushed(app, event)
            try
                app.mission = readMissionExcel(app.MissionFileNameEditField.Value);
                app.LoadMissionButton.BackgroundColor = [0 1 0];
                pause(1)
                app.LoadMissionButton.BackgroundColor = [0.96 0.96 0.96];
            catch missionFileError
                errorMessage = ['Unable to open file ''',app.MissionFileNameEditField.Value,'.txt''.'];
                errorMessage2 = ['Unable to open file ''',app.ConstraintFileNameEditField.Value,'''.'];
                if (strcmp(missionFileError.message,errorMessage)) || (strcmp(missionFileError.message,errorMessage2))
                    app.LoadMissionButton.BackgroundColor = [1 0 0];
                    pause(1)
                    app.LoadMissionButton.BackgroundColor = [0.96 0.96 0.96];
                end
            end
        end

        % Button pushed function: LoadRequirementsButton
        function LoadRequirementsButtonPushed(app, event)
            try
                app.reqs = readReqExcel(app.ConstraintFileNameEditField.Value);
                app.LoadRequirementsButton.BackgroundColor = [0 1 0];
                pause(1)
                app.LoadRequirementsButton.BackgroundColor = [0.96 0.96 0.96];
            catch reqFileError
                errorMessage = ['Unable to open file ''',app.ConstraintFileNameEditField.Value,'.txt''.'];
                errorMessage2 = ['Unable to open file ''',app.ConstraintFileNameEditField.Value,'''.'];
                if (strcmp(reqFileError.message,errorMessage)) || (strcmp(reqFileError.message,errorMessage2))
                    app.LoadRequirementsButton.BackgroundColor = [1 0 0];
                    pause(1)
                    app.LoadRequirementsButton.BackgroundColor = [0.96 0.96 0.96];
                end
            end
        end

        % Button pushed function: RunCaseButton
        function RunCaseButtonPushed(app, event)
            if app.aero.CLmax == 0
                app.aero.CLmax = app.CLmaxEditField.Value;
                app.aero.CLalpha = app.CLalphaEditField.Value;
                app.aero.CL0 = app.CL0EditField.Value;
                app.aero.CD0 = app.CD0EditField.Value;
                app.aero.CDR_TO = app.CDRTakeoffEditField.Value;
                app.aero.CDR_Land = app.CDRLandingEditField.Value;
                app.aero.K2 = app.InterferenceDragCoefficientK2EditField.Value;
                app.aero.AR = app.AspectRatioEditField.Value;
                app.aero.e = app.OswaldsEfficiencyEditField.Value;
                
                %Write aero write function
            end
            
            app.aero
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SizingandSynthesisToolLabel = uilabel(app.UIFigure);
            app.SizingandSynthesisToolLabel.Text = 'Sizing and Synthesis Tool';
            app.NameofVehicleEditFieldLabel = uilabel(app.UIFigure);
            app.NameofVehicleEditFieldLabel.Text = 'Name of Vehicle';
            app.NameofVehicleEditField = uieditfield(app.UIFigure, 'text');
            app.MissionAnalysisButton = uibutton(app.UIFigure, 'push');
            app.MissionAnalysisButton.Text = 'Mission Analysis';
            app.ConstraintAnalysisButton = uibutton(app.UIFigure, 'push');
            app.ConstraintAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ConstraintAnalysisButtonPushed, true);
            app.ConstraintAnalysisButton.Text = 'Constraint Analysis';
            app.RunCaseButton = uibutton(app.UIFigure, 'push');
            app.RunCaseButton.ButtonPushedFcn = createCallbackFcn(app, @RunCaseButtonPushed, true);
            app.RunCaseButton.Text = 'Run Case';
            app.CLmaxEditFieldLabel = uilabel(app.UIFigure);
            app.CLmaxEditFieldLabel.Text = 'CLmax';
            app.CLmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.AerodynamicDataLabel = uilabel(app.UIFigure);
            app.AerodynamicDataLabel.Text = 'Aerodynamic Data:';
            app.CLalphaEditFieldLabel = uilabel(app.UIFigure);
            app.CLalphaEditFieldLabel.Text = 'CLalpha';
            app.CLalphaEditField = uieditfield(app.UIFigure, 'numeric');
            app.CL0EditFieldLabel = uilabel(app.UIFigure);
            app.CL0EditFieldLabel.Text = 'CL0';
            app.CL0EditField = uieditfield(app.UIFigure, 'numeric');
            app.CD0EditFieldLabel = uilabel(app.UIFigure);
            app.CD0EditFieldLabel.Text = 'CD0';
            app.CD0EditField = uieditfield(app.UIFigure, 'numeric');
            app.CDRTakeoffEditFieldLabel = uilabel(app.UIFigure);
            app.CDRTakeoffEditFieldLabel.Text = 'CDR Takeoff';
            app.CDRTakeoffEditField = uieditfield(app.UIFigure, 'numeric');
            app.CDRLandingEditFieldLabel = uilabel(app.UIFigure);
            app.CDRLandingEditFieldLabel.Text = 'CDR Landing';
            app.CDRLandingEditField = uieditfield(app.UIFigure, 'numeric');
            app.InterferenceDragCoefficientK2EditFieldLabel = uilabel(app.UIFigure);
            app.InterferenceDragCoefficientK2EditFieldLabel.Text = 'Interference Drag Coefficient (K2)';
            app.InterferenceDragCoefficientK2EditField = uieditfield(app.UIFigure, 'numeric');
            app.AspectRatioEditFieldLabel = uilabel(app.UIFigure);
            app.AspectRatioEditFieldLabel.Text = 'Aspect Ratio';
            app.AspectRatioEditField = uieditfield(app.UIFigure, 'numeric');
            app.OswaldsEfficiencyEditFieldLabel = uilabel(app.UIFigure);
            app.OswaldsEfficiencyEditFieldLabel.Text = 'Oswald''s Efficiency';
            app.OswaldsEfficiencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.MissionFileNameEditFieldLabel = uilabel(app.UIFigure);
            app.MissionFileNameEditFieldLabel.Text = 'Mission File Name';
            app.MissionFileNameEditField = uieditfield(app.UIFigure, 'text');
            app.LoadMissionButton = uibutton(app.UIFigure, 'push');
            app.LoadMissionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMissionButtonPushed, true);
            app.LoadMissionButton.Text = 'Load Mission';
            app.LoadRequirementsButton = uibutton(app.UIFigure, 'push');
            app.LoadRequirementsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRequirementsButtonPushed, true);
            app.LoadRequirementsButton.Text = 'Load Requirements';
            app.ConstraintFileNameEditFieldLabel = uilabel(app.UIFigure);
            app.ConstraintFileNameEditFieldLabel.Text = 'Constraint File Name';
            app.ConstraintFileNameEditField = uieditfield(app.UIFigure, 'text');
            app.LoadAeroDataButton = uibutton(app.UIFigure, 'push');
            app.LoadAeroDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAeroDataButtonPushed, true);
            app.LoadAeroDataButton.Text = 'Load Aero Data';
            app.AeroFileNameEditFieldLabel = uilabel(app.UIFigure);
            app.AeroFileNameEditFieldLabel.Text = 'Aero File Name';
            app.AeroFileNameEditField = uieditfield(app.UIFigure, 'text');
            app.OnlyloadfilesiftheyaretheproperformatLabel = uilabel(app.UIFigure);
            app.OnlyloadfilesiftheyaretheproperformatLabel.Text = 'Only load files if they are the proper format';
            app.SamanthaCorradoandNathanCraneLabel = uilabel(app.UIFigure);
            app.SamanthaCorradoandNathanCraneLabel.Text = 'Samantha Corrado and Nathan Crane';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SizingSynthesisMaster

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end